classdef Analysis_Toolbox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Grid0                          matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        Main                           matlab.ui.container.Tab
        GridLayout                     matlab.ui.container.GridLayout
        ResourcePlanning               matlab.ui.control.Label
        home_2DPlots                   matlab.ui.control.Button
        home_3DPlots                   matlab.ui.control.Button
        home_Histograms                matlab.ui.control.Button
        home_Heatmaps                  matlab.ui.control.Button
        home_MARS_Report               matlab.ui.control.Button
        home_MARS_Analysis             matlab.ui.control.Button
        home_Interface_at_Limit        matlab.ui.control.Button
        home_MARS_Results_Summary      matlab.ui.control.Button
        TextArea_MARS_Report           matlab.ui.control.TextArea
        TextArea_MARS_Analysis         matlab.ui.control.TextArea
        TextArea_Interface_at_Limit    matlab.ui.control.TextArea
        TextArea_MARS_Results_Summary  matlab.ui.control.TextArea
        ResourcePlanning_2             matlab.ui.control.Label
        ResourcePlanning_3             matlab.ui.control.Label
        home_2DPlots_2                 matlab.ui.control.Button
        Histograms                     matlab.ui.container.Tab
        Grid_Histograms                matlab.ui.container.GridLayout
        BinLFUPanel                    matlab.ui.container.Panel
        BinLFUSpinner                  matlab.ui.control.Spinner
        ListBoxLabel                   matlab.ui.control.Label
        Tab2Group_3                    matlab.ui.container.TabGroup
        SummaryEventhrsNYBATab         matlab.ui.container.Tab
        UITable_2_4                    matlab.ui.control.Table
        SummaryEventsTab               matlab.ui.container.Tab
        UITable_2_3                    matlab.ui.control.Table
        Tab2Group_1                    matlab.ui.container.TabGroup
        PlotTab                        matlab.ui.container.Tab
        UIAxes_2_1                     matlab.ui.control.UIAxes
        Data_EventhrsTab               matlab.ui.container.Tab
        UITable_2_1                    matlab.ui.control.Table
        Tab2Group_2                    matlab.ui.container.TabGroup
        MaxDeficitTab                  matlab.ui.container.Tab
        UIAxes_2_2                     matlab.ui.control.UIAxes
        DurationTab                    matlab.ui.container.Tab
        UIAxes_2_3                     matlab.ui.control.UIAxes
        LossofEnergyTab                matlab.ui.container.Tab
        UIAxes_2_4                     matlab.ui.control.UIAxes
        ReasonTab                      matlab.ui.container.Tab
        UIAxes_2_5                     matlab.ui.control.UIAxes
        Data_EventsTab                 matlab.ui.container.Tab
        UITable_2_2                    matlab.ui.control.Table
        ListBox                        matlab.ui.control.ListBox
        BinSizePanel                   matlab.ui.container.Panel
        BinsEventsSlider               matlab.ui.control.Slider
        BinsSlider                     matlab.ui.control.Slider
        BinsSliderLabel_2              matlab.ui.control.Label
        BinsSliderLabel_3              matlab.ui.control.Label
        EventAreaButtonGroup           matlab.ui.container.ButtonGroup
        SystemButton                   matlab.ui.control.RadioButton
        NYBAButton                     matlab.ui.control.RadioButton
        EventTypeButtonGroup           matlab.ui.container.ButtonGroup
        EventhrsButton                 matlab.ui.control.RadioButton
        EventsButton                   matlab.ui.control.RadioButton
        EventslevelButtonGroup         matlab.ui.container.ButtonGroup
        GranNYBAButton                 matlab.ui.control.RadioButton
        GranAreasButton                matlab.ui.control.RadioButton
        DataTypeButtonGroup            matlab.ui.container.ButtonGroup
        MarginButton                   matlab.ui.control.RadioButton
        FlowButton                     matlab.ui.control.RadioButton
        NYCAPeakLoadPanel              matlab.ui.container.Panel
        NYBAPeakLoadEditField          matlab.ui.control.NumericEditField
        ExportDataButtonGroup          matlab.ui.container.ButtonGroup
        EventhrsDataButton             matlab.ui.control.Button
        EventsDataButton               matlab.ui.control.Button
        Heatmaps                       matlab.ui.container.Tab
        Grid_Heatmaps                  matlab.ui.container.GridLayout
        HeatmapsGroup                  matlab.ui.container.TabGroup
        EventhrsTab                    matlab.ui.container.Tab
        HourofMaxDeficitvsMonthTab     matlab.ui.container.Tab
        DurationvsHourofMaxDeficitTab  matlab.ui.container.Tab
        DurationvsMaxDeficitTab        matlab.ui.container.Tab
        DurationvsLossofEnergyTab      matlab.ui.container.Tab
        OtherPlotsNYBAResultsPanel     matlab.ui.container.Panel
        LOLEdyyearPanel                matlab.ui.container.Panel
        LOLEbox                        matlab.ui.control.NumericEditField
        LOLHhryearPanel                matlab.ui.container.Panel
        LOLHbox                        matlab.ui.control.NumericEditField
        LOEEMWhyearPanel               matlab.ui.container.Panel
        LOEEbox                        matlab.ui.control.NumericEditField
        LOLEplotwitheventhrsButton     matlab.ui.control.Button
        LOLHplotwitheventhrsButton     matlab.ui.control.Button
        LOEEplotwitheventhrsButton     matlab.ui.control.Button
        Plots3D                        matlab.ui.container.Tab
        Grid_Plots3D                   matlab.ui.container.GridLayout
        Plots3DGroup                   matlab.ui.container.TabGroup
        EventsTab_3D_1_1               matlab.ui.container.Tab
        UIAxes_3D_1_1                  matlab.ui.control.UIAxes
        EventsTab_3D_1_2               matlab.ui.container.Tab
        UIAxes_3D_1_2                  matlab.ui.control.UIAxes
        EventsTab_3D_2_1               matlab.ui.container.Tab
        UIAxes_3D_2_1                  matlab.ui.control.UIAxes
        EventsTab_3D_2_2               matlab.ui.container.Tab
        UIAxes_3D_2_2                  matlab.ui.control.UIAxes
        EventsTab_3D_3_2               matlab.ui.container.Tab
        UIAxes_3D_3_2                  matlab.ui.control.UIAxes
        EventsTab_3D_4_1               matlab.ui.container.Tab
        UIAxes_3D_4_1                  matlab.ui.control.UIAxes
        DataTab_3D_1                   matlab.ui.container.Tab
        UITable_3D_1                   matlab.ui.control.Table
        EventsTab_3D_3_1               matlab.ui.container.Tab
        UIAxes_3D_3_1                  matlab.ui.control.UIAxes
        SelectPlotListBoxLabel         matlab.ui.control.Label
        SelectPlotListBox              matlab.ui.control.ListBox
        SelectTypeButtonGroup          matlab.ui.container.ButtonGroup
        AreaPlotsbyBinButton           matlab.ui.control.RadioButton
        NYBAPlotsbyBinButton           matlab.ui.control.RadioButton
        ExportDataButton               matlab.ui.control.Button
        Plots2D                        matlab.ui.container.Tab
        Grid_Plots2D                   matlab.ui.container.GridLayout
        Plots2DGroup                   matlab.ui.container.TabGroup
        LOLEbyBinTab                   matlab.ui.container.Tab
        UIAxes_2D_1_1                  matlab.ui.control.UIAxes
        LOLHbyBinTab                   matlab.ui.container.Tab
        UIAxes_2D_1_2                  matlab.ui.control.UIAxes
        LOEEbyBinTab                   matlab.ui.container.Tab
        UIAxes_2D_1_3                  matlab.ui.control.UIAxes
        LOLEbyMarginTab                matlab.ui.container.Tab
        UIAxes_2D_1_4                  matlab.ui.control.UIAxes
        LOLHbyMarginTab                matlab.ui.container.Tab
        UIAxes_2D_1_5                  matlab.ui.control.UIAxes
        LOEEbyMarginTab                matlab.ui.container.Tab
        UIAxes_2D_1_6                  matlab.ui.control.UIAxes
        DataTab_2                      matlab.ui.container.Tab
        UITable_2D                     matlab.ui.control.Table
        NYBAResultsPanel_2             matlab.ui.container.Panel
        LOLEPlotwithEventsButton       matlab.ui.control.Button
        LOEEPlotwithEventsButton       matlab.ui.control.Button
        LOLHPlotwithEventsButton       matlab.ui.control.Button
        InitialDateDatePickerLabel     matlab.ui.control.Label
        InitialDateDatePicker          matlab.ui.control.DatePicker
        FinalDateDatePicker            matlab.ui.control.DatePicker
        FinalDateDatePickerLabel       matlab.ui.control.Label
        SummaryTab                     matlab.ui.container.Tab
        GridSummary                    matlab.ui.container.GridLayout
        SummaryTab_4                   matlab.ui.container.TabGroup
        Tab_4                          matlab.ui.container.Tab
        SummaryTab_3                   matlab.ui.container.TabGroup
        Tab_3                          matlab.ui.container.Tab
        SummaryTab_1                   matlab.ui.container.TabGroup
        Tab_1                          matlab.ui.container.Tab
        SummaryTab_2                   matlab.ui.container.TabGroup
        Tab_2                          matlab.ui.container.Tab
        menu                           matlab.ui.container.Panel
        menu_Grid                      matlab.ui.container.GridLayout
        menu_Grid_sub                  matlab.ui.container.GridLayout
        menu_Dir                       matlab.ui.container.GridLayout
        DirectoryEditField             matlab.ui.control.EditField
        Button                         matlab.ui.control.Button
        DirectoryLabel                 matlab.ui.control.Label
    end

    
    properties (Access = private)
        Directory
        Model      = [];
        Margins    = [];
        Flows      = [];
        Limits     = [];
        Areas      = {};
        NYBA_Areas = {};
        Interfaces = {};
        Item       = '';
        Bin        =  0;
        Type       = '';
        Year       =  0;
        NYSO_Colors_7  = 0;
        NYSO_Colors_11 = 0;
        ot10 = struct();
        ot16 = struct();
        Load = struct();
        Events = struct();
        Events_Hrs = struct();
        byMargins = struct();
        Temp = struct();
        utils = addsubmodule('GE-Utils');
        Histo_2_1
        Histo_2_2
        Histo_2_3
        Histo_2_4
        Histo_2_5
    end
    
    methods (Access = private)
        
        function ModeOperation(app)
            
            if     app.menu_Button0.Value; idx = 1;
            elseif app.menu_Button1.Value; idx = 2;
            elseif app.menu_Button2.Value; idx = 3;
            elseif app.menu_Button3.Value; idx = 4;
            elseif app.menu_Button4.Value; idx = 5;
            end
            
            app.tabGroup.SelectedTab = app.tabGroup.Children(idx);
            drawnow
            
        end
        
        function RunReportAnalysis(app,flag)
            if flag == 2
                decision = questdlg(sprintf(...
                    'MARS_Report and MARS_Analysis have not been run on the case. \n\n Would you like to run them now?')...
                    ,'MARS Post-Processing Option');
            else
                decision = questdlg(sprintf(...
                    'MARS_Analysis has not been run on the case. \n\n Would you like to run it now?')...
                    ,'MARS Post-Processing Option');
            end
            if strcmp(decision,'Yes')
                if flag == 1
                    f = app.UIFigure;
                    d = uiprogressdlg(f,'Title','Running MARS_Analysis...','Indeterminate','on');
                    MARS_Analysis;
                    close(d)
                    close all
                    DirectoryEditFieldValueChanged(app)
                else
                    f = app.UIFigure;
                    d = uiprogressdlg(f,'Title','Running MARS_Report...','Indeterminate','on');
                    MARS_Report;
                    close(d)
                    d = uiprogressdlg(f,'Title','Running MARS_Analysis...','Indeterminate','on');
                    MARS_Analysis;
                    close(d)
                    close all
                    DirectoryEditFieldValueChanged(app)
                end
            else
                h = msgbox(sprintf('This tool needs MARS_Report and MARS_Analysis to be run. Please select a new directory.')...
                    ,'MARS Message');
                uiwait(h)
                app.DirectoryEditField.Value = '';
            end
        end
        
        function TypeValueChange(app)
            if app.MarginButton.Value
                app.ListBoxLabel.Text = 'Area';
                app.ListBox.Items = app.Areas;
            else
                app.ListBoxLabel.Text = 'Interface';
                app.ListBox.Items = app.Interfaces;
            end
            app.ListBox.Value = {};
        end
        
        function data_import(app)
            app.ot10 = struct();
            app.ot16 = struct();
            app.Load.in02 = MARS.in02.load(true);
            app.Model = load(sprintf('%s\\_data\\MARS_Model.mat',app.Directory));
            app.ot10.System = MARS.ot10.load();
            app.ot10.NYBA = set_name(app.ot10.System, 'NYBA');
            
            app.Load.LS = app.Load.in02.sync(app.ot10.NYBA);
            app.ot16.System = sync(MARS.ot16.load(), app.ot10.System);
            app.ot16.NYBA = sync(app.ot16.System, app.ot10.NYBA);
            app.NYBA_Areas = cellstr(app.ot10.NYBA.Names(1:11));
            app.Year = num2str(year(app.ot10.NYBA.LOEE.Time(1)));
            
            f = app.UIFigure;
            d = uiprogressdlg(f,'Title','Loading loss of load events. Wait some minutes...','Indeterminate','on');
            get_durations(app);
            close(d)
            DatabyMargins(app);
            
            app.NYSO_Colors_7 = [
                0/255, 61/255, 87/255;     % Color 1
                0/255, 95/255, 134/255;    % Color 2
                43/255, 170/255, 221/255;  % Color 3
                0/255, 159/255, 77/255;    % Color 4
                242/255, 169/255, 0/255;   % Color 5
                254/255, 200/255, 0/255;   % Color 6
                179/255, 106/255, 148/255  % Color 7                   
            ];

            app.NYSO_Colors_11 = [
                0/255, 61/255, 87/255;     % Color 1
                43/255, 170/255, 221/255;  % Color 3
                70/255, 240/255, 240/255;    % Cyan
                0/255, 159/255, 77/255;    % Color 4
                0/255, 128/255, 128/255;     % Teal
                230/255, 25/255, 75/255;     % Red
                242/255, 169/255, 0/255;   % Color 5
                254/255, 200/255, 0/255;   % Color 6
                179/255, 106/255, 148/255  % Color 7
                145/255, 30/255, 180/255;    % Purple
                250/255, 190/255, 212/255;   % Pink
            ];             
        end       
              
        function update_summary(app)
            %Info for Histograms
                app.UITable_2_3.Data = app.Events.Statistic_Summary;
                app.UITable_2_4.Data = app.Events.Adequacy_Summary;
            %Info for Heatmaps
                plotHeatmap(app, app.EventhrsTab, true); % For hour data
                plotHeatmap(app, app.DurationvsMaxDeficitTab, false, 'NYBA_Max_Deficit');
                plotHeatmap(app, app.DurationvsLossofEnergyTab, false, 'NYBA_LOE');
                plotHeatmap(app, app.DurationvsHourofMaxDeficitTab, false, 'NYBA_Time_Max_Deficit');
                plotHeatmap(app, app.HourofMaxDeficitvsMonthTab, false, 'HourOfTheDay_vs_Month');
                
            %Info for Plots 3D
                % Call the functions to plot UIAxes
                plot_3D_1_1(app);
                plot_3D_1_2(app);
                plot_3D_2_1(app);
                plot_3D_2_2(app);
                plot_3D_3_1(app);
                plot_3D_3_2(app);
                plot_3D_4_1(app);
                
                data = removevars(app.Events.Data,{'Time_Start_End'});
                app.UITable_3D_1.Data = data(:,1:25);

            %info for Plots 2D
                app.InitialDateDatePicker.Value = min(app.Load.LS.Time);
                app.FinalDateDatePicker.Value = max(app.Load.LS.Time);
                app.Load.LFU_range = [min(app.Load.LS.Time), max(app.Load.LS.Time)];
                plotMode(app, app.ot10.NYBA.LOLE, app.UIAxes_2D_1_1,'ByLFU_Bin');
                plotMode(app, app.ot10.NYBA.LOLH, app.UIAxes_2D_1_2,'ByLFU_Bin');
                plotMode(app, app.ot10.NYBA.LOEE, app.UIAxes_2D_1_3,'ByLFU_Bin');
            
                data_bymargin = app.byMargins;
                NYBA_data_raw = data_bymargin(strcmp(data_bymargin.Name,'NYBA'),:);
                data_bymargin(data_bymargin.Month == 0, :) = [];
                NYBA_data = data_bymargin(strcmp(data_bymargin.Name,'NYBA'),:);
                plotMode(app, NYBA_data, app.UIAxes_2D_1_4, 'ByMargin','LOLE');
                plotMode(app, NYBA_data, app.UIAxes_2D_1_5, 'ByMargin','LOLH');
                plotMode(app, NYBA_data, app.UIAxes_2D_1_6, 'ByMargin','LOEE');
                NYBA_data_raw.Name = categorical(NYBA_data_raw.Name);
                app.UITable_2D.Data = NYBA_data_raw;
        end
        
        function DatabyMargins(app)
            workdir = pwd;
            path2metrics = fullfile(workdir,'_reports','Reliability_Metrics.csv');
            MyFolderInfo = dir(path2metrics);

            if ~isempty(MyFolderInfo)
                data = readtable( path2metrics, 'ReadVariableNames', true, 'ReadRowNames', false);
                for i=1:app.Load.LFU_bins
                    % Find rows corresponding to the current LFU_Bin
                    binRows = data.LFU_Bin == i;
                    % Multiply specific columns by the corresponding weight
                    data.LOLE(binRows) = data.LOLE(binRows) * app.ot10.NYBA.LOLE.Weight(i);
                    data.LOLH(binRows) = data.LOLH(binRows) * app.ot10.NYBA.LOLH.Weight(i);
                    data.LOEE(binRows) = data.LOEE(binRows) * app.ot10.NYBA.LOEE.Weight(i);
                end
                data.Weighted = [];
                
                 % Grouping the data by 'Name', 'Margin_State', and 'Month', and then summing the metrics
                groupedData = varfun(@sum, data, 'InputVariables', {'LOLE', 'LOLH', 'LOEE'}, ...
                                     'GroupingVariables', {'Name', 'Margin_State', 'Month'});               
                
                groupedData.Properties.VariableNames{'sum_LOLE'} = 'LOLE';
                groupedData.Properties.VariableNames{'sum_LOLH'} = 'LOLH';
                groupedData.Properties.VariableNames{'sum_LOEE'} = 'LOEE';
                groupedData.GroupCount = [];
                app.byMargins = groupedData;
            else
                error('No files found with pattern:\n%s',MyFolderInfo)
            end
        end

        function EventAreaSelect(app)
            if app.SystemButton.Value
                app.Margins = app.ot10.System.Interconnected.Margin;
                app.Flows = app.ot16.System.Flow;
                app.Areas = app.ot10.System.Names;
                app.Interfaces = app.ot16.System.Names;
            else
                app.Margins = app.ot10.NYBA.Interconnected.Margin;
                app.Flows = app.ot16.NYBA.Flow;
                app.Areas = app.ot10.NYBA.Names;
                app.Interfaces = app.ot16.NYBA.Names;                
            end

            if isempty(app.ListBox.Value)
                TypeValueChange(app)
            else
                if app.EventsButton.Value
                    update_events(app);
                else
                    Update_EventsHrs(app);
                end
            end
        end
        
        function Update_EventsHrs(app)
            
            if app.SystemButton.Value
                temp_ot10 = app.ot10.System;
                temp_ot16 = app.ot16.System;
            elseif app.MarginButton.Value
                temp_ot10 = app.ot10.NYBA;
                temp_ot16 = app.ot16.NYBA;
            else
                temp_ot10 = app.ot10.NYBA;
                temp_ot16 = app.ot16.NYBA;                
            end
            
            if app.MarginButton.Value
                app.UITable_2_4.Data = create_reports(temp_ot10, true);
                data = temp_ot10.Interconnected.Margin(temp_ot10.Interconnected.Index.Bin == app.Bin, app.Item == temp_ot10.Names);
            else
                data = temp_ot16.Flow(temp_ot16.Index.Bin == app.Bin, app.Item == temp_ot16.Names);
            end            
            
            if app.GranAreasButton.Value
                item = app.Item;
            else
                item = 'NYBA';
            end
            
            total_events = length(data);
            my_ot10 = temp_ot10.by_bin(app.Bin);
            NYBA = sum(app.Load.in02.filter('NYBA').by_event(my_ot10), 2);
            my_time = app.Load.in02.Time(my_ot10.Interconnected.Index.Hour);
            
            % Ensure all vectors have the same length before concatenating
            assert(length(data) == length(NYBA) && length(data)...
                == length(my_time),'Lengths of value, NYBA, and my_time vectors must match.');
            
            app.Temp = struct();
            app.Temp.Max_Deficit = data;
            app.Temp.Month = month(my_time);
            app.Temp.Day = day(my_time);
            app.Temp.Time_Max_Deficit = hour(my_time);
            app.Temp.NYBA_MW = NYBA;
            
            % Convert the structure to a table
            temp = struct2table(app.Temp);
            temp = temp(temp.Max_Deficit ~= 0, :);
            app.UITable_2_1.Data = temp;  

            data(data == 0) = [];
            app.Histo_2_1 = histogram_tool(app, data, app.UIAxes_2_1, total_events, item, "Events");
            app.Events_Hrs.Data = data;
        end

      
        function update_events(app)
            
            if ismember(string(app.Item), app.NYBA_Areas)
                d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on');
                d.Message = 'In progress...';
                
                data = removevars(app.Events.Data,{'Time_Start_End'});
                data = data(:,1:10);
                
                if app.GranAreasButton.Value
                    item = app.Item;
                    [~,ia] = ismember(item,app.Events.Area_Names);
                    data(:,8) = app.Events.Data(:,26+ia);
                    data(:,9) = app.Events.Data(:,37+ia);
                    data(:,10) = app.Events.Data(:,48+ia);   
                    data(:,11) = app.Events.Data(:,59+ia);
                else
                    item = 'NYBA';
                end
                
                logicalIndex = ismember(app.Events.Data.Bin, app.Bin);
                data = data(logicalIndex, :); % Use the logical index to filter the table
                total_events = height(data);
                data(data.Duration == 0, :) = []; % Remove rows where 'Duration' is zero
                data(data.NYBA_LOE == 0, :) = []; % Remove rows where 'Loss of Energy' is zero
                app.UITable_2_2.Data = data;
                app.Histo_2_2 = histogram_tool(app, data{:, 8}, app.UIAxes_2_2, total_events, item, "Events");
                app.Histo_2_3 = histogram_tool(app, data{:, 5}, app.UIAxes_2_3, total_events, item, "Events");
                app.Histo_2_4 = histogram_tool(app, data{:, 9}, app.UIAxes_2_4, total_events, item, "Events");
                app.Histo_2_5 = disp_tool(app, [data{:, 5}, data{:, 8}, data{:, 11}, data{:,10}], app.UIAxes_2_5, total_events, item, "Events");
                drawnow
                delete(d)
            end
        end
  
        function h = histogram_tool(app, data, UIAxes, total_events, item, type)
            [cax, args] = axescheck(UIAxes);
            h = histogram(cax, data, args{:});
            if type == "Event-hrs"
               app.BinsSlider.Value = min(max(app.BinsSlider.Limits), h.NumBins);
            else
               app.BinsEventsSlider.Value = min(max(app.BinsEventsSlider.Limits), h.NumBins);
            end
            titleString = sprintf('%s: %i of %i %s', item, height(data), total_events, type); % Use a different variable name for the title
            title(cax, titleString, 'interpreter', 'none');
        end
        
        function h = disp_tool(app, data, UIAxes, ~, ~, ~)
            data(data(:,1) == 0, :) = [];
            data(data(:,2) == 0, :) = [];
            for idx = 0:4
                %temp = data(data(:,3) ~= idx, :);
                temp = data;
                temp(temp(:,3) ~= idx, :) = [];
                if idx == 0
                    name = ['No Reserves'];
                elseif idx == 1
                    name = ['Internal Interface limit'];
                elseif idx == 2
                    name = ['External Interface limit'];
                elseif idx == 3
                    name = ['Int+Ext Interface limits'];
                elseif idx == 4
                    name = ['Internal Interface Out'];
                elseif idx == 5
                    name = ['Internal Interface Out + Limits'];
                end
                h = scatter3(UIAxes, temp(:,4), temp(:,1), temp(:,2),'DisplayName', name,'MarkerFaceColor', app.NYSO_Colors_7(7-idx, :));
                hold(UIAxes, 'on');
                temp = [];
            end          
            

            %titleString = sprintf('%s: %i of %i %s', item, height(data(:,1)), total_events, type); % Use a different variable name for the title
            hold(UIAxes, 'off');
            %view(UIAxes, viewAngle, 20);
            xlabel(UIAxes, 'Duration (hrs)');
            ylabel(UIAxes, 'Max Deficit (MW)');
            ylabel(UIAxes, 'Reason');
            legend(UIAxes, 'show');
        end        
       
        
       function get_durations(app)
           app.Temp = struct();
           app.Temp.NYBA = struct();
           app.Events.Area_Names(1:11) = cellstr(app.ot10.NYBA.Names(1:11));
           app.Events.Intf_Names(1:76) = cellstr(app.ot16.NYBA.Names(1:76));
           app.Load.LS = app.Load.in02.sync(app.ot10.NYBA);
           app.Load.NYBA = sum(app.Load.LS.LFU_Data, 3);
            
           for idx = 1:length(app.Events.Area_Names)
               item = app.Events.Area_Names{idx};
               item_pos = strcmp(item, app.Events.Area_Names);
               app.Load.(item) = app.Load.LS.LFU_Data(:,:,item_pos);
               app.Temp.(item) = struct();
           end
            
            num_bins = length(app.Load.NYBA(1,:));
            app.Load.LFU_bins = num_bins;
            input = app.ot10.NYBA.find_events();
            N = height(input);
            app.Events.Index = input(:, 1:3);
            max_duration = 36;
            suffixes = {'Time_Max_Deficit', 'Max_Deficit', 'LOE', 'Load','Reason'};
            
            varTypes = {};
            varArea_Names = {};

            specifiedColumns = {...
                'Replication','Bin','Month','Day','Duration','Time_Start_End',...
                'NYBA_Time_Max_Deficit','NYBA_Area_Max_Deficit','NYBA_Max_Deficit',...
                'NYBA_LOE','NYBA_Load','NYBA_Time_Max_Excess',...
                'NYBA_Area_Max_Excess','NYBA_Max_Excess','NYBA_Load_Excess'};
        
            varTypes = {...
                'double','double','double','double','double','cell',...
                'double','cell','double',...
                'double','double','double',...
                'cell','double','double'};
            
            % Generate varTypes and varArea_Names for dynamic columns
            for j = 1:numel(suffixes)
                for idx = 1:length(app.Events.Area_Names)
                    varTypes{end+1} = 'double';
                    varArea_Names{end+1} = [app.Events.Area_Names{idx} '_' suffixes{j}];
                end
            end
        
            varArea_Names = [specifiedColumns, varArea_Names];
            app.Events.Data = table('Size',[N 70],'VariableTypes',varTypes,'VariableNames',varArea_Names);  

            Internal_Intfs = [1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0;
                              1 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0;
                              0 1 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0;
                              0 0 0 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0;
                              0 0 1 1 1 1 0 0 0 0 0 0 0 0 0 0 0 0;
                              0 0 0 0 1 0 1 0 0 0 0 0 0 0 0 0 0 0;
                              0 0 0 0 0 1 1 1 0 0 0 0 0 0 0 0 0 0;
                              0 0 0 0 0 0 0 1 1 0 0 0 0 0 0 0 0 0;
                              0 0 0 0 0 0 0 0 1 1 1 0 0 0 0 0 0 0;
                              0 0 0 0 0 0 0 0 0 1 0 1 1 1 0 0 0 1;
                              0 0 0 0 0 0 0 0 0 0 1 1 0 0 0 1 1 0;];
                              %0 0 0 0 0 0 0 0 0 0 0 0 1 0 1 0 0 0;J1
                              %0 0 0 0 0 0 0 0 0 0 0 0 0 1 0 0 0 0;J2
                              %0 0 0 0 0 0 0 0 0 0 0 0 0 0 1 0 0 0;J3
                              %0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 1;J5
                              %0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 1 0 0;K1
                              %0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 1 0;K2
            External_Intfs = [1 0 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0;%A
                              0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0;%B
                              0 0 0 0 1 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0;%C
                              0 0 0 0 0 0 1 1 1 0 0 0 0 0 0 0 0 0 0 0;%D
                              0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0;%E
                              0 0 0 0 0 0 0 0 0 1 0 0 0 0 0 0 0 0 0 0;%F
                              0 0 0 0 0 0 0 0 0 0 1 1 0 0 0 1 0 0 0 1;%G
                              0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0;%H
                              0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0;%I
                              0 0 0 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0;%J
                              0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 1 0 0 0;];%K             
            
            for ii = 1:N
                app.Temp.All = input.Data{ii}.Variables;
                app.Temp.time = input.Data{ii}.Time;
                app.Temp.num_hours = size(app.Temp.All, 1);
                app.Temp.NYBA.flattened_temp = app.Temp.All(app.Temp.All < 0);
            
                if ~isempty(app.Temp.NYBA.flattened_temp)
            
                    app.Temp.NYBA.All = app.Temp.All;
            
                    if size(app.Temp.NYBA.All, 2) == 1
                        [app.Temp.NYBA.Deficit, app.Temp.NYBA.Time_Max_Deficit] = min(...
                            app.Temp.NYBA.All, [], 1, 'omitnan');
                        [app.Temp.NYBA.Excess, app.Temp.NYBA.Time_Max_Excess] = max(app.Temp.NYBA.All, [], 1, 'omitnan');
                    else
                        [app.Temp.NYBA.min_temp_per_row, app.Temp.NYBA.min_temp_per_col] = min(...
                            app.Temp.NYBA.All, [], 2, 'omitnan');
                        [app.Temp.NYBA.Deficit, app.Temp.NYBA.Time_Max_Deficit] = min(...
                            app.Temp.NYBA.min_temp_per_row);
            
                        [app.Temp.NYBA.max_temp_per_row, app.Temp.NYBA.max_temp_per_col] = max(...
                            app.Temp.NYBA.All, [], 2, 'omitnan');
                        [app.Temp.NYBA.Excess, app.Temp.NYBA.Time_Max_Excess] = max(...
                            app.Temp.NYBA.max_temp_per_row);
                    end
            
                    t = input(ii,2).Hour;
                    app.Temp.NYBA.load_bin_index_Max_Deficit = t + app.Temp.NYBA.Time_Max_Deficit - 1;
                    app.Temp.NYBA.load_bin_index_Max_Excess = t + app.Temp.NYBA.Time_Max_Excess - 1;
            
                    app.Events.Data.Replication(ii) = input(ii,1).Replication;
                    app.Events.Data.Bin(ii) = input(ii,3).Bin;
                    app.Events.Data.Month(ii) = month(app.Temp.time(app.Temp.NYBA.Time_Max_Deficit));
                    app.Events.Data.Day(ii) = day(app.Temp.time(app.Temp.NYBA.Time_Max_Deficit));
                    app.Events.Data.Duration(ii) = app.Temp.num_hours;
                    app.Events.Data.Time_Start_End(ii) = {[hour(app.Temp.time(1)); hour(app.Temp.time(end))]};
            
                    if app.Temp.NYBA.Deficit <= 0
                        app.Events.Data.NYBA_Time_Max_Deficit(ii) = hour(app.Temp.time(app.Temp.NYBA.Time_Max_Deficit));
                        app.Temp.NYBA.Area_Deficit = app.Temp.NYBA.min_temp_per_col(app.Temp.NYBA.Time_Max_Deficit);
                        app.Events.Data.NYBA_Area_Max_Deficit(ii) = cellstr(app.Events.Area_Names{app.Temp.NYBA.Area_Deficit});
                        app.Events.Data.NYBA_Max_Deficit(ii) = app.Temp.NYBA.Deficit;
                        app.Events.Data.NYBA_LOE(ii) = sum(app.Temp.NYBA.flattened_temp, 'all');
                        app.Events.Data.NYBA_Load(ii) = app.Load.NYBA(app.Temp.NYBA.load_bin_index_Max_Deficit, input(ii,3).Bin);
                    end
            
                    if app.Temp.NYBA.Excess > 0
                        app.Events.Data.NYBA_Time_Max_Excess(ii) = hour(app.Temp.time(app.Temp.NYBA.Time_Max_Excess));
                        app.Temp.NYBA.Area_Excess = app.Temp.NYBA.max_temp_per_col(app.Temp.NYBA.Time_Max_Excess);
                        app.Events.Data.NYBA_Area_Max_Excess(ii) = cellstr(app.Events.Area_Names{app.Temp.NYBA.Area_Excess});
                        app.Events.Data.NYBA_Max_Excess(ii) = app.Temp.NYBA.Excess;
                        app.Events.Data.NYBA_Load_Excess(ii) = app.Load.NYBA(app.Temp.NYBA.load_bin_index_Max_Excess, input(ii,3).Bin);
                    end
                    
                     row = find(app.ot16.NYBA.Index.Replication==input(ii,1).Replication...
                         & app.ot16.NYBA.Index.Hour==app.Temp.NYBA.load_bin_index_Max_Deficit & app.ot16.NYBA.Index.Bin==input(ii,3).Bin);
                     
                     Out = double(app.ot16.NYBA.InService(row, 1:length(app.Events.Intf_Names)));
                     Out(:, Out==1) = NaN;
                     Out(:, Out==0) = 1;
                     app.Events.Out(ii,1:length(app.Events.Intf_Names)) = Out;
                     At_Limit = app.ot16.NYBA.Slack(row,1:length(app.Events.Intf_Names));
                     At_Limit(:, At_Limit>5.00) = NaN;
                     At_Limit(:, At_Limit<-5.00) = NaN;
                     At_Limit(:, ~isnan(At_Limit)) = 1;
                     app.Events.At_Limit(ii,1:length(app.Events.Intf_Names)) = At_Limit;                         
            
                    for idx = 1:length(app.Events.Area_Names)
                         item = app.Events.Area_Names{idx};
                         item_pos = strcmp(item, app.Events.Area_Names);
                         app.Temp.(item).All = app.Temp.All(:,item_pos);
                         app.Temp.(item).flattened_temp = app.Temp.(item).All(app.Temp.(item).All < 0);
                         R1 = Internal_Intfs(idx,:).*At_Limit(1:18);
                         R2 = External_Intfs(idx,:).*At_Limit(19:38);
                         R4 = Internal_Intfs(idx,:).*Out(1:18);
                         %R5 = External_Intfs(idx,:).*Out(19:38);
                         R1(:, R1==0) = NaN;
                         R2(:, R2==0) = NaN;
                         R4(:, R4==0) = NaN;
                         R4(18) = NaN; %CHPE
                         %R5(:, R5==0) = NaN;
                    
                         if size(app.Temp.(item).All, 2) == 1
                             [app.Temp.(item).Deficit, app.Temp.(item).Time_Max_Deficit] = min(app.Temp.(item).All, [], 1, 'omitnan');
                         else
                             [app.Temp.(item).min_temp_per_row, ~] = min(app.Temp.(item).All, [], 2, 'omitnan');
                             [app.Temp.(item).Deficit, app.Temp.(item).Time_Max_Deficit] = min(app.Temp.(item).min_temp_per_row);
                         end
            
                         if app.Temp.(item).Deficit > 0
                            app.Temp.(item).Deficit = 0;
                         else
                             if nnz(~isnan(R1)) > 0 && nnz(~isnan(R2)) == 0
                                 app.Events.Data{ii,59+idx} = 1;
                             elseif nnz(~isnan(R2)) > 0 && nnz(~isnan(R1)) == 0
                                 app.Events.Data{ii,59+idx} = 2;
                             elseif nnz(~isnan(R2)) > 0 && nnz(~isnan(R1)) > 0
                                 app.Events.Data{ii,59+idx} = 3;
                             elseif nnz(~isnan(R4)) > 0 && app.Events.Data{ii,59+idx} == 0
                                 app.Events.Data{ii,59+idx} = 4;
                             elseif nnz(~isnan(R4)) > 0 && app.Events.Data{ii,59+idx} ~= 0
                                 app.Events.Data{ii,59+idx} = 5;
                             end
                            
                         end
                         t = input(ii,2).Hour;
                         app.Temp.(item).load_bin_index_Max_Deficit = t + app.Temp.(item).Time_Max_Deficit - 1;
                         app.Events.Data{ii,15+idx} = hour(app.Temp.time(app.Temp.(item).Time_Max_Deficit));
                         app.Events.Data{ii,26+idx} = app.Temp.(item).Deficit;
                         app.Events.Data{ii,37+idx} = sum(app.Temp.(item).flattened_temp, 'all');
                         app.Events.Data{ii,48+idx} = app.Load.(item)(app.Temp.(item).load_bin_index_Max_Deficit,input(ii,3).Bin);
                    end
                end
            end
            
            duration = zeros(max_duration, num_bins);
            metrics = zeros(num_bins, 5);
            
            for jj = 1:num_bins
                app.Temp.Duration = app.Events.Data.Duration(app.Events.Index.Bin == jj);
                app.Temp.Duration = max(1, round(app.Temp.Duration));
                duration(:, jj) = accumarray(app.Temp.Duration, ones(size(app.Temp.Duration)), [max_duration, 1], [], 0);
                metrics(jj, :) = [length(app.Temp.Duration), mean(app.Temp.Duration),...
                    std(app.Temp.Duration), kurtosis(app.Temp.Duration, 0), skewness(app.Temp.Duration, 0)];
            end
            
            app.Events.Duration = array2table(horzcat(transpose(1:max_duration), duration),'VariableNames',...
                horzcat({'Duration'}, cellstr("Bin_" + (1:num_bins))));
            app.Events.Statistic_Summary = array2table(metrics,'RowNames', cellstr("Bin " + (1:num_bins)),...
                'VariableNames', {'Count', 'Mean', 'StDev', 'Kurtosis', 'Skewness'});
            [app.Events.Adequacy_Summary, ~] = create_reports(app.ot10.NYBA, true);
       end

  
        function plotHeatmap(app, obj, isHour, type)
            
            c = flipud(hot);
            c(1, :) = [1, 1, 1]; % Set the first color as white
            
            if isHour
                Area = app.ot10.NYBA.LOEE;
                hr_idx = hour(Area.Time);
                mon_idx = month(Area.Time);
                dy_hr = hr_idx(Area.Interval);
                mon = mon_idx(Area.Interval);
                months = {'Jan', 'Feb', 'Mar', 'Apr', 'May', 'Jun', 'Jul', 'Aug', 'Sep', 'Oct', 'Nov', 'Dec'};
                data = zeros(12, 24);
                for ii = 1:12
                    for jj = 0:23
                        data(ii, jj+1) = sum(mon == ii & dy_hr == jj);
                    end
                end             
                hm = heatmap(obj, data,...
                    'GridVisible', 'on',...
                    'ColorbarVisible', 'on',...
                    'ColorMethod','count',...
                    'Colormap', c,...
                    'XLabel', 'Hour of the Day',...
                    'YLabel', 'Month',...
                     'YDisplayLabel', months);%'CellLabelColor', 'none',...
                set(hm,'Title', [str2double(app.Year) "NYBA Events-hrs"]);
                
            else
                
                if strcmp(type, 'NYBA_Max_Deficit')
                    lowest = min(app.Events.Data.NYBA_Max_Deficit);
                    if lowest > -2000
                        edges = -2000:50:0;
                    elseif lowest < -2000
                        edges = -2500:100:0;
                    elseif lowest < -2500
                        edges = -3000:100:0;
                    else
                        edges = -3500:100:0;
                    end
                    
                    values = edges(1:end-1);
                    Y = table(app.Events.Data.Duration, discretize(...
                        app.Events.Data.NYBA_Max_Deficit,edges,values),'VariableNames',{'Duration','NYBA_Max_Deficit'});
       
                    hm = heatmap(obj, Y, 'Duration', 'NYBA_Max_Deficit',...
                        'GridVisible', 'on',...
                        'ColorbarVisible', 'on',...
                        'ColorMethod','count',...
                        'Colormap', c,...
                        'XLabel', 'Duration (hrs)',...
                        'YLabel', 'Max Deficit (MW)');
                    
                elseif strcmp(type, 'NYBA_LOE')
                    
                    lowest = min(app.Events.Data.NYBA_LOE);
                    if lowest > -2000
                        edges = -2000:100:0;
                    elseif lowest < -2000
                        edges = -2500:100:0;
                    elseif lowest < -2500
                        edges = -3000:250:0;
                    else
                        edges = -4000:250:0;
                    end
                    
                    values = edges(1:end-1);
                    Y = table(app.Events.Data.Duration, discretize(...
                        app.Events.Data.NYBA_LOE,edges,values),'VariableNames',{'Duration','NYBA_LOE'});
                    
                    hm = heatmap(obj, Y, 'Duration', 'NYBA_LOE',...
                        'GridVisible', 'on',...
                        'ColorbarVisible', 'on',...
                        'ColorMethod','count',...
                        'Colormap', c,...
                        'XLabel', 'Duration (hrs)',...
                        'YLabel', 'Loss of Energy (MWh)');
                    
                elseif strcmp(type, 'NYBA_Time_Max_Deficit')
                    
                    Y = table(app.Events.Data.Duration,...
                       app.Events.Data.NYBA_Time_Max_Deficit,'VariableNames',{'Duration','NYBA_Time_Max_Deficit'});
                    
                    newRow = Y(1, :);
                    newRow{1, 1:end} = NaN;
                    for i = 1:24
                        if ~any(Y.NYBA_Time_Max_Deficit == i)
                            newRow.NYBA_Time_Max_Deficit = i;
                            Y = [Y; newRow];
                        end
                    end
                    
                    hm = heatmap(obj, Y, 'Duration', 'NYBA_Time_Max_Deficit',...
                        'GridVisible', 'on',...
                        'ColorbarVisible', 'on',...
                        'ColorMethod','count',...
                        'Colormap', c,...
                        'XLabel', 'Duration (hrs)',...
                        'YLabel', 'Hour of Max Deficit');
                    
                elseif strcmp(type, 'Month')
                    
                    Y = table(app.Events.Data.Duration,...
                        app.Events.Data.Month,'VariableNames',{'Duration','Month'});
                    newRow = Y(1, :);
                    newRow{1, 1:end} = NaN;
                    for i = 1:12
                        if ~any(Y.Month == i)
                            newRow.Month = i;
                            Y = [Y; newRow];
                        end
                    end                    

                    months = {'Jan', 'Feb', 'Mar', 'Apr', 'May', 'Jun', 'Jul', 'Aug', 'Sep', 'Oct', 'Nov', 'Dec'};
                    
                    hm = heatmap(obj, Y, 'Duration', 'Month',...
                        'GridVisible', 'on',...
                        'ColorbarVisible', 'on',...
                        'ColorMethod','count',...
                        'Colormap', c,...
                        'XLabel', 'Duration (hrs)',...
                        'YLabel', 'Month',...
                        'YDisplayLabel', months);%'CellLabelColor', 'none',...
                    
                 elseif strcmp(type, 'HourOfTheDay_vs_Month')
                     
                    Y = table(app.Events.Data.NYBA_Time_Max_Deficit, app.Events.Data.Month,'VariableNames',{'NYBA_Time_Max_Deficit','Month'});
                    newRow = Y(1, :);
                    newRow{1, 1:end} = NaN;
                    for i = 1:24
                        if ~any(Y.NYBA_Time_Max_Deficit == i)
                            newRow.NYBA_Time_Max_Deficit = i;
                            Y = [Y; newRow];
                        end
                    end                 

                    newRow{1, 1:end} = NaN;
                    for i = 1:12
                        if ~any(Y.Month == i)
                            newRow.Month = i;
                            Y = [Y; newRow];
                        end
                    end                    
                    
                    months = {'Jan', 'Feb', 'Mar', 'Apr', 'May', 'Jun', 'Jul', 'Aug', 'Sep', 'Oct', 'Nov', 'Dec'};
                    hm = heatmap(obj, Y, 'NYBA_Time_Max_Deficit', 'Month',...
                        'GridVisible', 'on',...
                        'ColorbarVisible', 'on',...
                        'ColorMethod','count',...
                        'Colormap', c,...
                        'CellLabelColor', 'none',...
                        'XLabel', 'Hour of Max Deficit',...
                        'YLabel', 'Month',...
                        'YDisplayLabel', months);                  
                end
                    set(hm,'Title', [str2double(app.Year) "NYBA Events"]);
            end
        end
        
        function finalizePlot(~,uiAxes, viewAngle, xLabel, yLabel, zLabel)
            hold(uiAxes, 'off');
            view(uiAxes, viewAngle, 20);
            xlabel(uiAxes, xLabel);
            ylabel(uiAxes, yLabel);
            zlabel(uiAxes, zLabel);
            legend(uiAxes, 'show');
            
            if strcmp(xLabel, 'Month')
                months = {'Jan', 'Feb', 'Mar', 'Apr', 'May', 'Jun', 'Jul', 'Aug', 'Sep', 'Oct', 'Nov', 'Dec'};
                xticks(uiAxes, 1:12); % Use 1:12 for the actual tick values
                xticklabels(uiAxes, months(1:12)); % Display the months as tick labels
            elseif strcmp(yLabel, 'Month')
                months = {'Jan', 'Feb', 'Mar', 'Apr', 'May', 'Jun', 'Jul', 'Aug', 'Sep', 'Oct', 'Nov', 'Dec'};
                yticks(uiAxes, 1:12); % Use 1:12 for the actual tick values
                yticklabels(uiAxes, months(1:12)); % Display the months as tick labels                
            elseif strcmp(zLabel, 'Month')
                months = {'Jan', 'Feb', 'Mar', 'Apr', 'May', 'Jun', 'Jul', 'Aug', 'Sep', 'Oct', 'Nov', 'Dec'};
                zticks(uiAxes, 1:12); % Use 1:12 for the actual tick values
                zticklabels(uiAxes, months(1:12)); % Display the months as tick labels                
            end
        end                   
        
        % plot_3D_1_1
        function plot_3D_1_1(app)
            app.Temp = app.Events.Data;
            app.Temp(app.Temp.Duration == 0, :) = []; % Remove rows where 'Duration' is zero
            
            if app.NYBAPlotsbyBinButton.Value
                for bin = 1:app.Load.LFU_bins
                    temp = app.Temp(app.Temp.Bin == bin, :);
                    temp.NYBA_LOE(temp.NYBA_LOE == 0, :) = NaN;
                    load_level = ['Bin level ' num2str(bin)];
                    scatter3(app.UIAxes_3D_1_1, temp.NYBA_Load, temp.Duration, temp.NYBA_Max_Deficit, ...
                             'DisplayName', load_level, 'MarkerFaceColor', app.NYSO_Colors_7(bin, :));
                    hold(app.UIAxes_3D_1_1, 'on');
                end
            else
                for idx = 1:length(app.NYBA_Areas)
                    item = app.NYBA_Areas{idx};
                    area = ['Area ' item];
                    temp = app.Temp(app.Temp{:,26+idx} ~= 0, :);
                    scatter3(app.UIAxes_3D_1_1, temp{:,48+idx}, temp.Duration, temp{:,26+idx}, ...
                             'DisplayName', area, 'MarkerFaceColor', app.NYSO_Colors_11(12-idx, :));
                    hold(app.UIAxes_3D_1_1, 'on');
                end        
            end
            finalizePlot(app, app.UIAxes_3D_1_1, 225, 'Load (MW)', 'Duration (hrs)', 'Max Deficit (MW)');   
        end
        
        % plot_3D_1_2
        function plot_3D_1_2(app)
            app.Temp = app.Events.Data;
            app.Temp(app.Temp.Duration == 0, :) = []; % Remove rows where 'Duration' is zero
            
            if app.NYBAPlotsbyBinButton.Value
                for bin = 1:app.Load.LFU_bins
                    temp = app.Temp(app.Temp.Bin == bin, :);
                    temp.NYBA_LOE(temp.NYBA_LOE == 0, :) = NaN;
                    load_level = ['Bin level ' num2str(bin)];
                    scatter3(app.UIAxes_3D_1_2, temp.NYBA_Load, temp.Duration, temp.NYBA_Max_Deficit,...
                        abs(temp.NYBA_LOE)./20,'DisplayName', load_level, 'MarkerFaceColor', app.NYSO_Colors_7(bin, :));
                    hold(app.UIAxes_3D_1_2, 'on');
                end
            else
                for idx = 1:length(app.NYBA_Areas)
                    item = app.NYBA_Areas{idx};
                    area = ['Area ' item];
                    temp = app.Temp(app.Temp{:,26+idx} ~= 0, :);
                    temp{:,37+idx}(temp{:,37+idx} == 0 , :) = NaN;
                    scatter3(app.UIAxes_3D_1_2, temp{:,48+idx}, temp.Duration, temp{:,26+idx}, ...
                             abs(temp{:,37+idx})./20, 'DisplayName', area,'MarkerFaceColor', app.NYSO_Colors_11(12-idx, :));
                    hold(app.UIAxes_3D_1_2, 'on');
                end                
            end
            finalizePlot(app, app.UIAxes_3D_1_2, 225, 'Load (MW)', 'Duration (hrs)', 'Max Deficit (MW)');   
        end        

        % plot_3D_2_1
        function plot_3D_2_1(app)
            app.Temp = app.Events.Data;
            app.Temp(app.Temp.Duration == 0, :) = []; % Remove rows where 'Duration' is zero

            if app.NYBAPlotsbyBinButton.Value
                for bin = 1:app.Load.LFU_bins
                    temp = app.Temp(app.Temp.Bin == bin, :);
                    temp.NYBA_LOE(temp.NYBA_LOE == 0, :) = NaN;
                    load_level = ['Bin level ' num2str(bin)];
                    scatter3(app.UIAxes_3D_2_1, temp.NYBA_Load, temp.Duration, temp.Month,...
                       'DisplayName', load_level, 'MarkerFaceColor', app.NYSO_Colors_7(bin, :));
                    hold(app.UIAxes_3D_2_1, 'on');
                end
            else
                for idx = 1:length(app.NYBA_Areas)
                    item = app.NYBA_Areas{idx};
                    area = ['Area ' item];
                    temp = app.Temp(app.Temp{:,26+idx} ~= 0, :);
                    scatter3(app.UIAxes_3D_2_1, temp{:,48+idx}, temp.Duration, temp.Month, ...
                             'DisplayName', area,'MarkerFaceColor', app.NYSO_Colors_11(12-idx, :));
                    hold(app.UIAxes_3D_2_1, 'on');
                end                
            end
            finalizePlot(app, app.UIAxes_3D_2_1, 225, 'Load (MW)', 'Duration (hrs)', 'Month');   
        end
        
        % plot_3D_2_2
        function plot_3D_2_2(app)
            app.Temp = app.Events.Data;
            app.Temp(app.Temp.Duration == 0, :) = []; % Remove rows where 'Duration' is zero

            if app.NYBAPlotsbyBinButton.Value
                for bin = 1:app.Load.LFU_bins
                    temp = app.Temp(app.Temp.Bin == bin, :);
                    temp.NYBA_LOE(temp.NYBA_LOE == 0, :) = NaN;
                    load_level = ['Bin level ' num2str(bin)];
                    scatter3(app.UIAxes_3D_2_2, temp.NYBA_Load, temp.Duration, temp.Month,...
                       abs(temp.NYBA_LOE)./20,'DisplayName', load_level, 'MarkerFaceColor', app.NYSO_Colors_7(bin, :));
                    hold(app.UIAxes_3D_2_2, 'on');
                end
            else
                for idx = 1:length(app.NYBA_Areas)
                    item = app.NYBA_Areas{idx};
                    area = ['Area ' item];
                    temp = app.Temp(app.Temp{:,26+idx} ~= 0, :);
                    temp{:,37+idx}(temp{:,37+idx} == 0 , :) = NaN;
                    scatter3(app.UIAxes_3D_2_2, temp{:,48+idx}, temp.Duration, temp.Month, ...
                             abs(temp{:,37+idx})./20,'DisplayName', area,'MarkerFaceColor', app.NYSO_Colors_11(12-idx, :));
                    hold(app.UIAxes_3D_2_2, 'on');
                end                
            end
            finalizePlot(app, app.UIAxes_3D_2_2, 225, 'Load (MW)', 'Duration (hrs)', 'Month');   
        end
        
        % plot_3D_3_1
        function plot_3D_3_1(app)
            app.Temp = app.Events.Data;
            app.Temp(app.Temp.Duration == 0, :) = []; % Remove rows where 'Duration' is zero
            if app.NYBAPlotsbyBinButton.Value
                for bin = 1:app.Load.LFU_bins
                    temp = app.Temp(app.Temp.Bin == bin, :);
                    temp.NYBA_LOE(temp.NYBA_LOE == 0, :) = NaN;
                    load_level = ['Bin level ' num2str(bin)];
                    scatter3(app.UIAxes_3D_3_1, temp.NYBA_Load, temp.Duration, temp.NYBA_Time_Max_Deficit,...
                        'DisplayName', load_level, 'MarkerFaceColor', app.NYSO_Colors_7(bin, :));
                    hold(app.UIAxes_3D_3_1, 'on');
                end
            else
                for idx = 1:length(app.NYBA_Areas)
                    item = app.NYBA_Areas{idx};
                    area = ['Area ' item];
                    temp = app.Temp(app.Temp{:,26+idx} ~= 0, :);
                    temp{:,37+idx}(temp{:,37+idx} == 0 , :) = NaN;
                    scatter3(app.UIAxes_3D_3_1, temp{:,48+idx}, temp.Duration, temp{:,15+idx}, ...
                        'DisplayName', area,'MarkerFaceColor', app.NYSO_Colors_11(12-idx, :));
                    hold(app.UIAxes_3D_3_1, 'on');
                end                
            end
            finalizePlot(app, app.UIAxes_3D_3_1, 225, 'Load (MW)', 'Duration (hrs)', 'Hour of Max Deficit');   
        end        
        
        % plot_3D_3_2
        function plot_3D_3_2(app)
            app.Temp = app.Events.Data;
            app.Temp(app.Temp.Duration == 0, :) = []; % Remove rows where 'Duration' is zero
            if app.NYBAPlotsbyBinButton.Value
                for bin = 1:app.Load.LFU_bins
                    temp = app.Temp(app.Temp.Bin == bin, :);
                    temp.NYBA_LOE(temp.NYBA_LOE == 0, :) = NaN;
                    load_level = ['Bin level ' num2str(bin)];
                    scatter3(app.UIAxes_3D_3_2, temp.NYBA_Load, temp.Duration, temp.NYBA_Time_Max_Deficit,...
                        abs(temp.NYBA_LOE)./20,'DisplayName', load_level, 'MarkerFaceColor', app.NYSO_Colors_7(bin, :));
                    hold(app.UIAxes_3D_3_2, 'on');
                end
            else
                for idx = 1:length(app.NYBA_Areas)
                    item = app.NYBA_Areas{idx};
                    area = ['Area ' item];
                    temp = app.Temp(app.Temp{:,26+idx} ~= 0, :);
                    temp{:,37+idx}(temp{:,37+idx} == 0 , :) = NaN;
                    scatter3(app.UIAxes_3D_3_2, temp{:,48+idx}, temp.Duration, temp{:,15+idx}, ...
                        abs(temp{:,37+idx})./20,'DisplayName', area,'MarkerFaceColor', app.NYSO_Colors_11(12-idx, :));
                    hold(app.UIAxes_3D_3_2, 'on');
                end                
            end
            finalizePlot(app, app.UIAxes_3D_3_2, 225, 'Load (MW)', 'Duration (hrs)', 'Hour of Max Deficit');   
        end

        % plot_3D_4_1
        function plot_3D_4_1(app)
            app.Temp = app.Events.Data;
            app.Temp(app.Temp.Duration == 0, :) = []; % Remove rows where 'Duration' is zero
            
            if app.NYBAPlotsbyBinButton.Value
                for bin = 1:app.Load.LFU_bins
                    temp = app.Temp(app.Temp.Bin == bin, :);
                    temp.NYBA_LOE(temp.NYBA_LOE == 0, :) = NaN;
                    load_level = ['Bin level ' num2str(bin)];
                    scatter3(app.UIAxes_3D_4_1, temp.NYBA_Load, temp.Duration, temp.NYBA_LOE, ...
                             'DisplayName', load_level, 'MarkerFaceColor', app.NYSO_Colors_7(bin, :));
                    hold(app.UIAxes_3D_4_1, 'on');
                end
            else
                for idx = 1:length(app.NYBA_Areas)
                    item = app.NYBA_Areas{idx};
                    area = ['Area ' item];
                    temp = app.Temp(app.Temp{:,37+idx} ~= 0, :);
                    scatter3(app.UIAxes_3D_4_1, temp{:,48+idx}, temp.Duration, temp{:,37+idx}, ...
                             'DisplayName', area, 'MarkerFaceColor', app.NYSO_Colors_11(12-idx, :));
                    hold(app.UIAxes_3D_4_1, 'on');
                end                
            end
            finalizePlot(app, app.UIAxes_3D_4_1, 225, 'Load (MW)', 'Duration (hrs)', 'Loss of Energy (MWh)'); 
        end        

        function plotMode(app, data, uiAxes, Mode, Metric)   
            months = {'Jan', 'Feb', 'Mar', 'Apr', 'May', 'Jun', 'Jul', 'Aug', 'Sep', 'Oct', 'Nov', 'Dec'};
            
            if strcmp(Mode, 'ByLFU_Bin')
                app.Temp = data.byMonth;
                unit = strtrim(char(data.Unit));
                type = strtrim(char(data.Type));
                ylabelString = sprintf('%s (%s)', type, unit);
                numMonths = size(app.Temp, 1);
                for i = 1:size(app.Temp, 2)
                    displayLabel = ['Level ' num2str(i)];
                    plot(uiAxes, 1:numMonths, app.Temp(:, i), '-', ...
                        'LineWidth', 2, 'Color', app.NYSO_Colors_7(i, :), 'Marker', 'o', 'DisplayName', displayLabel);
                    hold(uiAxes, 'on');
                    grid(uiAxes, 'on');
                    box(uiAxes, 'on');
                end
                
            elseif strcmp(Mode, 'ByMargin')
                if strcmp(Metric, 'LOLE')
                    ylabelString = "LOLE (dy/yr)";
                elseif strcmp(Metric, 'LOLH')
                    ylabelString = "LOLH (hr/yr)";
                elseif strcmp(Metric, 'LOEE')
                    ylabelString = "LOEE (MWh/yr)";
                end
                uniqueMonths = unique(data.Month);
                uniqueMargins = unique(data.Margin_State);
                numMonths = length(uniqueMonths);
                for i = 1:length(uniqueMargins)
                    displayLabel = ['Margin ' num2str(i)];
                    app.Temp = data(data.Margin_State == i, :);
                    plot(uiAxes, uniqueMonths, app.Temp.(Metric), '-', ...
                        'LineWidth', 2, 'Color', app.NYSO_Colors_11(i, :), 'Marker', 'o', 'DisplayName', displayLabel);
                    hold(uiAxes, 'on');
                    grid(uiAxes, 'on');
                    box(uiAxes, 'on');
                end                
            end
            
            hold(uiAxes, 'off');
            xlabel(uiAxes, 'Month');
            ylabel(uiAxes, ylabelString);
            legend(uiAxes, 'show');
            title(uiAxes, str2double(app.Year), 'FontWeight', 'bold', 'FontSize', 12);
            xticks(uiAxes, 1:numMonths);
            xticklabels(uiAxes, months);
            ytickformat(uiAxes, '%.3f'); % Format to three decimal places
            set(uiAxes, 'GridColor', 'w', 'MinorGridColor', 'w', 'GridAlpha', 0.5, 'Color', [225/255, 230/255, 235/255]);  
        end
        
        function update_histogram_resuts(app, Mode)
            if app.MarginButton.Value
                set( findall(app.UITable_2_4, '-property', 'Visible'), 'Visible', 'on');
                set( findall(app.EventTypeButtonGroup, '-property', 'Visible'), 'Visible', 'on');
                if app.NYBAButton.Value && app.EventsButton.Value
                    set( findall(app.UIAxes_2_2, '-property', 'Visible'), 'Visible', 'on');
                    set( findall(app.UIAxes_2_3, '-property', 'Visible'), 'Visible', 'on');
                    set( findall(app.UIAxes_2_3, '-property', 'Visible'), 'Visible', 'on');
                    set( findall(app.UIAxes_2_4, '-property', 'Visible'), 'Visible', 'on');
                    set( findall(app.UITable_2_2, '-property', 'Visible'), 'Visible', 'on');
                    set( findall(app.UITable_2_3, '-property', 'Visible'), 'Visible', 'on');
                    set( findall(app.BinsEventsSlider, '-property', 'Visible'), 'Visible', 'on');
                    set( findall(app.EventsButton, '-property', 'Value'), 'Value', true);
                    set( findall(app.EventslevelButtonGroup, '-property', 'Visible'), 'Visible', 'on');
                    if strcmp(Mode, 'on')
                        Update_EventsHrs(app);
                        update_events(app);
                    end
                 else
                    set( findall(app.UIAxes_2_2, '-property', 'Visible'), 'Visible', 'off');
                    set( findall(app.UIAxes_2_3, '-property', 'Visible'), 'Visible', 'off');
                    set( findall(app.UIAxes_2_3, '-property', 'Visible'), 'Visible', 'off');
                    set( findall(app.UIAxes_2_4, '-property', 'Visible'), 'Visible', 'off');
                    set( findall(app.UITable_2_2, '-property', 'Visible'), 'Visible', 'off');
                    set( findall(app.UITable_2_3, '-property', 'Visible'), 'Visible', 'off');
                    set( findall(app.BinsEventsSlider, '-property', 'Visible'), 'Visible', 'off');
                    set( findall(app.EventslevelButtonGroup, '-property', 'Visible'), 'Visible', 'off');
                    set( findall(app.EventsButton, '-property', 'Value'), 'Value', false);
                    set( findall(app.EventhrsButton, '-property', 'Value'), 'Value', true);
                    if strcmp(Mode, 'on')
                        Update_EventsHrs(app);
                    end
                end
            else
                set( findall(app.EventTypeButtonGroup, '-property', 'Visible'), 'Visible', 'off');
                set( findall(app.UIAxes_2_2, '-property', 'Visible'), 'Visible', 'off');
                set( findall(app.UIAxes_2_3, '-property', 'Visible'), 'Visible', 'off');
                set( findall(app.UIAxes_2_3, '-property', 'Visible'), 'Visible', 'off');
                set( findall(app.UIAxes_2_4, '-property', 'Visible'), 'Visible', 'off');
                set( findall(app.UITable_2_2, '-property', 'Visible'), 'Visible', 'off');
                set( findall(app.UITable_2_3, '-property', 'Visible'), 'Visible', 'off');
                set( findall(app.UITable_2_4, '-property', 'Visible'), 'Visible', 'off');
                set( findall(app.BinsEventsSlider, '-property', 'Visible'), 'Visible', 'off');
                set( findall(app.EventslevelButtonGroup, '-property', 'Visible'), 'Visible', 'off');
                set( findall(app.EventsButton, '-property', 'Value'), 'Value', false);
                set( findall(app.EventhrsButton, '-property', 'Value'), 'Value', true);
                if strcmp(Mode, 'on')
                    Update_EventsHrs(app);
                end
            end
        end
        
        function showDialog(app)
            % Display the message box
            selection = uiconfirm(app.UIFigure, 'Would you like to visualize GE MARS results?', 'Confirmation', ...
                'Options', {'Yes', 'No'},'DefaultOption', 1, 'CancelOption', 2);
            % Handle the user's response
            switch selection
                case 'Yes'
                    data_import(app);
                    EventAreaSelect(app);
                    update_summary(app);
                case 'No'
            end            
        end
                
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            mainMonitor = get(0, 'MonitorPositions');
            
            [~, idx]    = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(idx,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4)-30)/2)];            
        end

        % Value changed function: DirectoryEditField
        function DirectoryEditFieldValueChanged(app, event)

            app.Directory = app.DirectoryEditField.Value;
            
            if ~isempty(app.DirectoryEditField.Value)
                f = app.UIFigure;
                d = uiprogressdlg(f,'Title','Loading Margin and Flow Files...','Indeterminate','on');
                % Preserve the current directory
                originalDir = pwd;
                if isfolder(app.Directory)
                    cd(app.Directory);
                else
                    msg = sprintf('Invalid directory: %s', app.Directory);
                    uialert(f, msg, 'Directory Error');
                    close(d);
                    return;
                end            
                
                mat_check = dir(fullfile(strcat(app.Directory,'\_data\'),'*.mat'));
                ot10_check = fullfile(strcat(app.Directory,'\_data\'),'AreaData.mat');
                ot16_check = fullfile(strcat(app.Directory,'\_data\'),'IntfData.mat');
                if isempty(mat_check)
                    mif_check = dir(fullfile(strcat(app.Directory,'\input\'),'*.in05'));
                    ot09_check = dir(fullfile(strcat(app.Directory,'\output\'),'*.ot09'));
                    if isempty(mif_check)
                        h = msgbox('The chosen directory does not have a MARS case','Directory Error');
                        uiwait(h);
                    elseif isempty(ot09_check)
                        h = msgbox('This MARS case has not been run','MARS Case Error');
                        uiwait(h);
                    else
                        RunReportAnalysis(app,2);
                    end
                elseif length(mat_check) == 3
                    RunReportAnalysis(app,1);
                elseif ~exist(ot10_check,'file') || ~exist(ot16_check,'file')
                    RunReportAnalysis(app,2);
                else
                    showDialog(app);
                    cd(originalDir);
                end
                close(d);
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            webWindows = matlab.internal.webwindowmanager.instance.findAllWebwindows(); %#ok<NASGU>
            webWindows = matlab.internal.webwindowmanager.instance.windowList;
            for ii = 1:length(webWindows)
                webWindow = webWindows(ii);
                if strcmp( webWindow.Title, app.UIFigure.Name )
                    break
                end
            end
            parent_Path = app.DirectoryEditField.Value;
            pathname = uigetdir(app.DirectoryEditField.Value);
            if ~isequal(pathname,parent_Path) && ~isequal(pathname,0)
                app.DirectoryEditField.Value = pathname;
                webWindow.setAlwaysOnTop(true);
                webWindow.setAlwaysOnTop(false);
                DirectoryEditFieldValueChanged(app, event);
            end            
        end

        % Button pushed function: home_2DPlots, home_3DPlots, 
        % home_Heatmaps, home_Histograms
        function Menu_QuickAccess(app, event)
            switch event.Source
                case app.home_Histograms; app.TabGroup.SelectedTab = app.Histograms;
                case app.home_Heatmaps; app.TabGroup.SelectedTab = app.Heatmaps;
                case app.home_3DPlots; app.TabGroup.SelectedTab = app.Plots3D;
                case app.home_2DPlots; app.TabGroup.SelectedTab = app.Plots2D;
            end             
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            %app.EventhrsDataButton.Enable = 'on';
            app.Item = app.ListBox.Value;
            app.Bin = app.BinLFUSpinner.Value;
            
            if app.MarginButton.Value
                app.Type = 'Margin';
            else
                app.Type = 'Flow';
            end
            format long g;
            monthly_peak_load = max(app.Load.in02.filter('NYBA'));
            app.NYBAPeakLoadEditField.Value = max(monthly_peak_load(:, app.Bin));
            update_histogram_resuts(app, 'on');
        end

        % Selection changed function: EventAreaButtonGroup
        function EventAreaButtonGroupSelectionChanged(app, event)
            if isempty(app.ListBox.Value)
                TypeValueChange(app)
            else
                update_histogram_resuts(app, 'on');
            end
        end

        % Selection changed function: DataTypeButtonGroup
        function DataTypeButtonGroupSelectionChanged(app, event)
            if isempty(app.ListBox.Value)
                TypeValueChange(app)
                return
            end
            if isempty(app.Areas) || isempty(app.Interfaces)
                return
            else
                TypeValueChange(app)
            end
            update_histogram_resuts(app, 'off');
        end

        % Button pushed function: EventhrsDataButton
        function EventhrsDataButtonPushed(app, event)
            % Create the base filename based on app.Item and app.Bin
            baseFilename = sprintf('%s_Bin%i', app.Item, app.Bin);

            % Open a save dialog and get the selected path and base filename
            [file, path] = uiputfile([baseFilename '_event_hrs.csv'], 'Save Event-Hours and Events Files for Item selected');
            if isequal(file,0) || isequal(path,0)
                return;
            else
                fullFilePath_1 = fullfile(path, file);
                fullFilePath_2 = fullfile(path,'All_events.csv');
            
                if app.MarginButton.Value
                    writematrix(app.Events_Hrs.Data, fullFilePath_1);
                    writetable(app.Events.Data, fullFilePath_2);
                else
                    writematrix(app.Events_Hrs.Data,fullFilePath_1);
                end
            end                        
        end

        % Value changing function: BinsSlider
        function BinsSliderValueChanging(app, event)
            changingValue = event.Value;
            % Ensure that changingValue is a positive integer
            changingValue = max(1, round(changingValue));
            app.Histo_2_1.NumBins = changingValue; 
        end

        % Value changing function: BinsEventsSlider
        function BinsEventsSliderValueChanging(app, event)
            selectedTab = app.Tab2Group_2.SelectedTab;
            changingValue = event.Value;
            
            % Ensure that changingValue is a positive integer
            changingValue = max(1, round(changingValue));
    
            % Check which tab is selected and perform actions accordingly
            if strcmp(selectedTab.Title, 'Max Deficit')
                app.Histo_2_2.NumBins = changingValue;
            elseif strcmp(selectedTab.Title, 'Duration')
                app.Histo_2_3.NumBins = changingValue;
            elseif strcmp(selectedTab.Title, 'Loss of Energy')
                app.Histo_2_4.NumBins = changingValue;
            elseif strcmp(selectedTab.Title, 'Reason')
                app.Histo_2_5.NumBins = changingValue;  
            else
                app.Tab2Group_2.selectedTab(app.MaxDeficitTab);
                app.Histo_2_2.NumBins = changingValue;
            end
        end

        % Value changed function: BinLFUSpinner
        function BinLFUSpinnerValueChanged(app, event)
            app.Item = app.ListBox.Value;
            app.Bin = app.BinLFUSpinner.Value;
            
            if app.MarginButton.Value
                app.Type = 'Margin';
            else
                app.Type = 'Flow';
            end
            format long g;
            monthly_peak_load = max(app.Load.in02.filter('NYBA'));
            app.NYBAPeakLoadEditField.Value = max(monthly_peak_load(:, app.Bin));
            update_histogram_resuts(app, 'on');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Selection changed function: EventTypeButtonGroup
        function EventTypeButtonGroupSelectionChanged(app, event)
            if isempty(app.ListBox.Value)
                TypeValueChange(app)
                return
            end
            update_histogram_resuts(app, 'on'); 
        end

        % Selection changed function: EventslevelButtonGroup
        function EventslevelButtonGroupSelectionChanged(app, event)
            update_histogram_resuts(app, 'on');     
        end

        % Button pushed function: LOLEplotwitheventhrsButton
        function LOLEplotwitheventhrsButtonPushed(app, event)
            if ~isempty(app.ot10.NYBA)
                heatmap(app.ot10.NYBA.LOLE);
            end
        end

        % Button pushed function: LOLHplotwitheventhrsButton
        function LOLHplotwitheventhrsButtonPushed(app, event)
            if ~isempty(app.ot10.NYBA)
                heatmap(app.ot10.NYBA.LOLH);
            end
        end

        % Button pushed function: LOEEplotwitheventhrsButton
        function LOEEplotwitheventhrsButtonPushed(app, event)
            if ~isempty(app.ot10.NYBA)
                heatmap(app.ot10.NYBA.LOEE);
            end
        end

        % Value changed function: SelectPlotListBox
        function SelectPlotListBoxValueChanged(app, event)
            value = app.SelectPlotListBox.Value;
            if value == "(1.1) MW_Load / Hrs / Max Deficit"
                app.Plots3DGroup.SelectedTab = app.EventsTab_3D_1_1;
            elseif value == "(1.2) MW_Load / Hrs / Max Deficit - weighted by Loss of Energy"
                app.Plots3DGroup.SelectedTab = app.EventsTab_3D_1_2;
            elseif value == "(2.1) MW_Load / Hrs / Month"
                app.Plots3DGroup.SelectedTab = app.EventsTab_3D_2_1;          
            elseif value == "(2.2) MW_Load / Hrs / Month - weighted by Loss of Energy"
                app.Plots3DGroup.SelectedTab = app.EventsTab_3D_2_2;
            elseif value == "(3.1) MW_Load / Hrs / Hour of Max Deficit"
                app.Plots3DGroup.SelectedTab = app.EventsTab_3D_3_1;
            elseif value == "(3.2) MW_Load / Hrs / Hour of Max Deficit - weighted by Loss of Energy"
                app.Plots3DGroup.SelectedTab = app.EventsTab_3D_3_2;
            elseif value == "(4.1) MW_Load / Hrs / Loss of Energy"
                app.Plots3DGroup.SelectedTab = app.EventsTab_3D_4_1;
            elseif value == "(5.1) Relative Severity_1 / Hrs / Hour of Max Deficit"
                app.Plots3DGroup.SelectedTab = app.EventsTab_3D_5_1;
            elseif value == "(5.2) Relative Severity_2 / Hrs / Hour of Max Deficit"
                app.Plots3DGroup.SelectedTab = app.EventsTab_3D_5_2;         
            elseif value == "Data"
                app.Plots3DGroup.SelectedTab = app.DataTab_3D_1;
            end
        end

        % Selection changed function: SelectTypeButtonGroup
        function SelectTypeButtonGroupSelectionChanged(app, event)
            %selectedButton = app.SelectTypeButtonGroup.SelectedObject;
            if ~isempty(app.DirectoryEditField.Value)
                f = app.UIFigure;
                d = uiprogressdlg(f,'Title','Loading loss of load events...','Indeterminate','on');
                plot_3D_1_1(app);
                plot_3D_1_2(app);
                plot_3D_2_1(app);
                plot_3D_2_2(app);
                plot_3D_3_1(app);
                plot_3D_3_2(app);
                plot_3D_4_1(app);
                close(d);
            end
        end

        % Button pushed function: LOLEPlotwithEventsButton
        function LOLEPlotwithEventsButtonPushed(app, event)
            plot_with_events(app.Load.LS, app.ot10.NYBA.LOLE);
            xlim(app.Load.LFU_range);
        end

        % Button pushed function: LOLHPlotwithEventsButton
        function LOLHPlotwithEventsButtonPushed(app, event)
            plot_with_events(app.Load.LS, app.ot10.NYBA.LOLH);
            xlim(app.Load.LFU_range);
        end

        % Button pushed function: LOEEPlotwithEventsButton
        function LOEEPlotwithEventsButtonPushed(app, event)
            plot_with_events(app.Load.LS, app.ot10.NYBA.LOEE);
            xlim(app.Load.LFU_range);
        end

        % Callback function
        function InitialDateDatePickerValueChanged(app, event)
            %value = app.InitialDateDatePicker.Value;
            initialDate = app.InitialDateDatePicker.Value;
            finalDate = app.FinalDateDatePicker.Value;
            
            % Check if the initial date is earlier than the final date
            if initialDate < finalDate
                % If initial date is earlier, proceed with your logic
                app.Load.LFU_range = [initialDate, finalDate];
            else
                app.Load.LFU_range = [finalDate, initialDate];
            end
        end

        % Callback function
        function FinalDateDatePickerValueChanged(app, event)
            %value = app.InitialDateDatePicker.Value;
            initialDate = app.InitialDateDatePicker.Value;
            finalDate = app.FinalDateDatePicker.Value;
            
            % Check if the initial date is earlier than the final date
            if initialDate < finalDate
                % If initial date is earlier, proceed with your logic
                app.Load.LFU_range = [initialDate, finalDate];
            else
                app.Load.LFU_range = [finalDate, initialDate];
            end
                data_bymargin = app.byMargins;
                NYBA_data_raw = data_bymargin(strcmp(data_bymargin.Name, 'NYBA'), :);
                data_bymargin(data_bymargin.Month == 0, :) = [];
                NYBA_data = data_bymargin(strcmp(data_bymargin.Name, 'NYBA'), :);
                plotMode(app, NYBA_data, app.UIAxes_2D_2_1, 'ByMargin', 'LOLE');
                plotMode(app, NYBA_data, app.UIAxes_2D_2_2, 'ByMargin', 'LOLH');
                plotMode(app, NYBA_data, app.UIAxes_2D_2_3, 'ByMargin', 'LOEE');
                NYBA_data_raw.Name = categorical(NYBA_data_raw.Name);
                app.UITable_2D_2_1.Data = NYBA_data_raw;
        end

        % Callback function
        function ListBox_2DValueChanged(app, event)
            %value = app.ListBox_2D.Value;
        end

        % Button pushed function: home_MARS_Report
        function home_MARS_ReportButtonPushed(app, event)
            if ~isempty(app.DirectoryEditField.Value)
                f = app.UIFigure;
                d = uiprogressdlg(f,'Title','Running MARS_Report...','Indeterminate','on');
                MARS_Report;
                close(d);
            end
        end

        % Button pushed function: home_MARS_Analysis
        function home_MARS_AnalysisButtonPushed(app, event)
            if ~isempty(app.DirectoryEditField.Value)
                f = app.UIFigure;
                d = uiprogressdlg(f,'Title','Running MARS_Analysis...','Indeterminate','on');
                MARS_Analysis;
                close(d);
            end
        end

        % Button pushed function: home_Interface_at_Limit
        function home_Interface_at_LimitButtonPushed(app, event)
            if ~isempty(app.DirectoryEditField.Value)
                f = app.UIFigure;
                d = uiprogressdlg(f,'Title','Running interface_at_limit...','Indeterminate','on');
                interface_at_limit;
                close(d);
            end            
        end

        % Button pushed function: home_MARS_Results_Summary
        function home_MARS_Results_SummaryButtonPushed(app, event)
            if ~isempty(app.DirectoryEditField.Value)
                f = app.UIFigure;
                d = uiprogressdlg(f,'Title','Running MARS_Results_Summary...','Indeterminate','on');
                MARS_Results_Summary;
                close(d);
            end                
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            % Create the base filename based on app.Item and app.Bin
            baseFilename = sprintf('Extreme_events_summary');

            % Open a save dialog and get the selected path and base filename
            [file, path] = uiputfile([baseFilename '.csv'], 'Save Extreme Events Summary File');
            if isequal(file,0) || isequal(path,0)
                return;
            else
                fullFilePath_1 = fullfile(path, file);
                writetable(app.UITable_3D_1.Data,fullFilePath_1);
            end 
        end

        % Button pushed function: EventsDataButton, home_2DPlots_2
        function EventsDataButtonPushed(app, event)
            % Open a save dialog and get the selected path and base filename
            [file, path] = uiputfile(['All_events.csv'], 'Save Events Data File');
            if isequal(file,0) || isequal(path,0)
                return;
            else
            writetable(app.Events.Data, path);
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Grid0 = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.Grid0);
            app.Main = uitab(app.TabGroup);
            app.Main.Title = 'Main';
            app.GridLayout = uigridlayout(app.Main);
            app.ResourcePlanning = uilabel(app.GridLayout);
            app.ResourcePlanning.Text = 'Analysis Tool Resource Planning';
            app.home_2DPlots = uibutton(app.GridLayout, 'push');
            app.home_2DPlots.ButtonPushedFcn = createCallbackFcn(app, @Menu_QuickAccess, true);
            app.home_2DPlots.Tag = 'Mosaic1';
            app.home_2DPlots.Text = '2D Plots';
            app.home_3DPlots = uibutton(app.GridLayout, 'push');
            app.home_3DPlots.ButtonPushedFcn = createCallbackFcn(app, @Menu_QuickAccess, true);
            app.home_3DPlots.Tag = 'Mosaic4';
            app.home_3DPlots.Text = '3D Plots';
            app.home_Histograms = uibutton(app.GridLayout, 'push');
            app.home_Histograms.ButtonPushedFcn = createCallbackFcn(app, @Menu_QuickAccess, true);
            app.home_Histograms.Tag = 'Mosaic2';
            app.home_Histograms.Text = 'Histograms';
            app.home_Heatmaps = uibutton(app.GridLayout, 'push');
            app.home_Heatmaps.ButtonPushedFcn = createCallbackFcn(app, @Menu_QuickAccess, true);
            app.home_Heatmaps.Tag = 'Mosaic3';
            app.home_Heatmaps.Text = ' Heatmaps';
            app.home_MARS_Report = uibutton(app.GridLayout, 'push');
            app.home_MARS_Report.ButtonPushedFcn = createCallbackFcn(app, @home_MARS_ReportButtonPushed, true);
            app.home_MARS_Report.Tag = 'Mosaic2';
            app.home_MARS_Report.Text = 'MARS Report';
            app.home_MARS_Analysis = uibutton(app.GridLayout, 'push');
            app.home_MARS_Analysis.ButtonPushedFcn = createCallbackFcn(app, @home_MARS_AnalysisButtonPushed, true);
            app.home_MARS_Analysis.Tag = 'Mosaic2';
            app.home_MARS_Analysis.Text = 'MARS Analysis';
            app.home_Interface_at_Limit = uibutton(app.GridLayout, 'push');
            app.home_Interface_at_Limit.ButtonPushedFcn = createCallbackFcn(app, @home_Interface_at_LimitButtonPushed, true);
            app.home_Interface_at_Limit.Tag = 'Mosaic2';
            app.home_Interface_at_Limit.Text = 'Interface at Limit';
            app.home_MARS_Results_Summary = uibutton(app.GridLayout, 'push');
            app.home_MARS_Results_Summary.ButtonPushedFcn = createCallbackFcn(app, @home_MARS_Results_SummaryButtonPushed, true);
            app.home_MARS_Results_Summary.Tag = 'Mosaic2';
            app.home_MARS_Results_Summary.Text = 'MARS Results Summary';
            app.TextArea_MARS_Report = uitextarea(app.GridLayout);
            app.TextArea_MARS_Report.Value = {'This application provides an overview of the results from MARS. Principle features include:'; ''; '* Processing of the ot09 and ot11 files to create a CSV containing the reliability metrics for each area by load level, margin state, and month.'; '* Processing of the ot07 file to create:'; '  * the generator summary table'; '  * the installed capacity data for each area'};
            app.TextArea_MARS_Analysis = uitextarea(app.GridLayout);
            app.TextArea_MARS_Analysis.Value = {'This application processes the margin and flow output files to provide advanced analytics, such as:'; ''; '* A summary of loss of load events by load level for the specified area, pool, or group (default: `ICNARP` value)'; '* Summary statistics for:'; '  * Margin surpluses and deficits by area'; '  * Flows and limits by interface and interface group'};
            app.TextArea_Interface_at_Limit = uitextarea(app.GridLayout);
            app.TextArea_Interface_at_Limit.Value = {'This user interactive script outputs a summary of percentage of event hours each interface and interface group is at it''s respective limit.'; ''; '* This script requires the following to have been run on the directory:'; '  * MARS_Report'; '  * MARS_Analysis'};
            app.TextArea_MARS_Results_Summary = uitextarea(app.GridLayout);
            app.TextArea_MARS_Results_Summary.Value = {'This application summarizes LOLE, LOLH and LOEE results from multiple MARS cases into a single csv.'};
            app.ResourcePlanning_2 = uilabel(app.GridLayout);
            app.ResourcePlanning_2.Text = 'Run MARS postprocessing tools';
            app.ResourcePlanning_3 = uilabel(app.GridLayout);
            app.ResourcePlanning_3.Text = 'Visualize GE MARS results ';
            app.home_2DPlots_2 = uibutton(app.GridLayout, 'push');
            app.home_2DPlots_2.ButtonPushedFcn = createCallbackFcn(app, @EventsDataButtonPushed, true);
            app.home_2DPlots_2.Tag = 'Mosaic1';
            app.home_2DPlots_2.Text = {'MARS'; 'Events Report'};
            app.Histograms = uitab(app.TabGroup);
            app.Histograms.Title = 'Histograms';
            app.Grid_Histograms = uigridlayout(app.Histograms);
            app.BinLFUPanel = uipanel(app.Grid_Histograms);
            app.BinLFUPanel.Title = 'Bin (LFU)';
            app.BinLFUSpinner = uispinner(app.BinLFUPanel);
            app.BinLFUSpinner.Limits = [1 7];
            app.BinLFUSpinner.ValueDisplayFormat = '%.0f';
            app.BinLFUSpinner.ValueChangedFcn = createCallbackFcn(app, @BinLFUSpinnerValueChanged, true);
            app.BinLFUSpinner.Value = 1;
            app.ListBoxLabel = uilabel(app.Grid_Histograms);
            app.ListBoxLabel.Text = '';
            app.Tab2Group_3 = uitabgroup(app.Grid_Histograms);
            app.SummaryEventhrsNYBATab = uitab(app.Tab2Group_3);
            app.SummaryEventhrsNYBATab.Title = 'Summary: Event-hrs (NYBA)';
            app.UITable_2_4 = uitable(app.SummaryEventhrsNYBATab);
            app.UITable_2_4.ColumnName = {'Event-hrs'; 'Event-days'; 'LOLE (dy/year)'; 'LOLH (hr/year)'; 'LOEE (MWh/year)'};
            app.UITable_2_4.RowName = {'Bin_1'; 'Bin_2'; 'Bin_3'; 'Bin_4'; 'Bin_5'; 'Bin_6'; 'Bin_7'; 'Totals'};
            app.SummaryEventsTab = uitab(app.Tab2Group_3);
            app.SummaryEventsTab.Title = 'Summary: Events';
            app.UITable_2_3 = uitable(app.SummaryEventsTab);
            app.UITable_2_3.ColumnName = {'Count'; 'Mean'; 'StDev'; 'Kurtosis'; 'Skewness'};
            app.UITable_2_3.RowName = {'Bin_1'; 'Bin_2'; 'Bin_3'; 'Bin_4'; 'Bin_5'; 'Bin_6'; 'Bin_7'};
            app.Tab2Group_1 = uitabgroup(app.Grid_Histograms);
            app.PlotTab = uitab(app.Tab2Group_1);
            app.PlotTab.Title = 'Plot';
            app.UIAxes_2_1 = uiaxes(app.PlotTab);
            title(app.UIAxes_2_1, 'Events-hrs')
            xlabel(app.UIAxes_2_1, 'Hourly Deficit (MW)')
            ylabel(app.UIAxes_2_1, 'Frequency (occurrences)')
            app.Data_EventhrsTab = uitab(app.Tab2Group_1);
            app.Data_EventhrsTab.Title = 'Data_Event-hrs';
            app.UITable_2_1 = uitable(app.Data_EventhrsTab);
            app.UITable_2_1.ColumnName = {'Max Deficit (MW)'; 'Month'; 'Day'; 'HourOfDay'; 'NYBA_MW'};
            app.UITable_2_1.RowName = {};
            app.Tab2Group_2 = uitabgroup(app.Grid_Histograms);
            app.MaxDeficitTab = uitab(app.Tab2Group_2);
            app.MaxDeficitTab.Title = 'Max Deficit';
            app.UIAxes_2_2 = uiaxes(app.MaxDeficitTab);
            title(app.UIAxes_2_2, 'Events ')
            xlabel(app.UIAxes_2_2, 'Max Deficit (MW)')
            ylabel(app.UIAxes_2_2, 'Frequency (occurrences)')
            app.DurationTab = uitab(app.Tab2Group_2);
            app.DurationTab.Title = 'Duration';
            app.UIAxes_2_3 = uiaxes(app.DurationTab);
            title(app.UIAxes_2_3, 'Events ')
            xlabel(app.UIAxes_2_3, 'Duration (hrs)')
            ylabel(app.UIAxes_2_3, 'Frequency (occurrences)')
            app.LossofEnergyTab = uitab(app.Tab2Group_2);
            app.LossofEnergyTab.Title = 'Loss of Energy';
            app.UIAxes_2_4 = uiaxes(app.LossofEnergyTab);
            title(app.UIAxes_2_4, 'Events ')
            xlabel(app.UIAxes_2_4, 'Loss of Energy (MWh)')
            ylabel(app.UIAxes_2_4, 'Frequency (occurrences)')
            app.ReasonTab = uitab(app.Tab2Group_2);
            app.ReasonTab.Title = 'Reason';
            app.UIAxes_2_5 = uiaxes(app.ReasonTab);
            title(app.UIAxes_2_5, 'Events ')
            xlabel(app.UIAxes_2_5, 'Max Deficit (MW)')
            ylabel(app.UIAxes_2_5, 'Frequency (occurrences)')
            app.Data_EventsTab = uitab(app.Tab2Group_2);
            app.Data_EventsTab.Title = 'Data_Events';
            app.UITable_2_2 = uitable(app.Data_EventsTab);
            app.UITable_2_2.ColumnName = {'Replication'; 'Bin'; 'Month'; 'Day'; 'Duration'; 'Time_Max_Deficit'; 'Area_Max_Deficit'; 'MW_Max_Deficit'; 'MWh_LOE'; 'Load'};
            app.UITable_2_2.RowName = {};
            app.ListBox = uilistbox(app.Grid_Histograms);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.BinSizePanel = uipanel(app.Grid_Histograms);
            app.BinSizePanel.Title = 'Bin Size';
            app.BinsEventsSlider = uislider(app.BinSizePanel);
            app.BinsEventsSlider.MajorTicks = [0 25 50 75 100];
            app.BinsEventsSlider.MajorTickLabels = {''};
            app.BinsEventsSlider.ValueChangingFcn = createCallbackFcn(app, @BinsEventsSliderValueChanging, true);
            app.BinsEventsSlider.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.BinsSlider = uislider(app.BinSizePanel);
            app.BinsSlider.MajorTicks = [0 25 50 75 100];
            app.BinsSlider.MajorTickLabels = {''};
            app.BinsSlider.ValueChangingFcn = createCallbackFcn(app, @BinsSliderValueChanging, true);
            app.BinsSlider.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.BinsSliderLabel_2 = uilabel(app.BinSizePanel);
            app.BinsSliderLabel_2.Text = 'Event-hrs';
            app.BinsSliderLabel_3 = uilabel(app.BinSizePanel);
            app.BinsSliderLabel_3.Text = 'Events';
            app.EventAreaButtonGroup = uibuttongroup(app.Grid_Histograms);
            app.EventAreaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EventAreaButtonGroupSelectionChanged, true);
            app.EventAreaButtonGroup.Title = 'Event Area';
            app.SystemButton = uiradiobutton(app.EventAreaButtonGroup);
            app.SystemButton.Text = 'System';
            app.NYBAButton = uiradiobutton(app.EventAreaButtonGroup);
            app.NYBAButton.Text = 'NYBA';
            app.NYBAButton.Value = true;
            app.EventTypeButtonGroup = uibuttongroup(app.Grid_Histograms);
            app.EventTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EventTypeButtonGroupSelectionChanged, true);
            app.EventTypeButtonGroup.Title = 'Event Type';
            app.EventhrsButton = uiradiobutton(app.EventTypeButtonGroup);
            app.EventhrsButton.Text = 'Event-hrs';
            app.EventhrsButton.Value = true;
            app.EventsButton = uiradiobutton(app.EventTypeButtonGroup);
            app.EventsButton.Text = 'Events';
            app.EventslevelButtonGroup = uibuttongroup(app.Grid_Histograms);
            app.EventslevelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EventslevelButtonGroupSelectionChanged, true);
            app.EventslevelButtonGroup.Title = 'Events: level';
            app.GranNYBAButton = uiradiobutton(app.EventslevelButtonGroup);
            app.GranNYBAButton.Text = 'NYBA';
            app.GranAreasButton = uiradiobutton(app.EventslevelButtonGroup);
            app.GranAreasButton.Text = 'Areas';
            app.GranAreasButton.Value = true;
            app.DataTypeButtonGroup = uibuttongroup(app.Grid_Histograms);
            app.DataTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataTypeButtonGroupSelectionChanged, true);
            app.DataTypeButtonGroup.Title = 'Data Type';
            app.MarginButton = uiradiobutton(app.DataTypeButtonGroup);
            app.MarginButton.Text = 'Margin';
            app.MarginButton.Value = true;
            app.FlowButton = uiradiobutton(app.DataTypeButtonGroup);
            app.FlowButton.Text = 'Flow';
            app.NYCAPeakLoadPanel = uipanel(app.Grid_Histograms);
            app.NYCAPeakLoadPanel.Title = 'NYCA Peak Load';
            app.NYBAPeakLoadEditField = uieditfield(app.NYCAPeakLoadPanel, 'numeric');
            app.NYBAPeakLoadEditField.Limits = [0 Inf];
            app.NYBAPeakLoadEditField.ValueDisplayFormat = '%.0f';
            app.NYBAPeakLoadEditField.Editable = 'off';
            app.ExportDataButtonGroup = uibuttongroup(app.Grid_Histograms);
            app.ExportDataButtonGroup.Title = 'Export Data';
            app.EventhrsDataButton = uibutton(app.ExportDataButtonGroup, 'push');
            app.EventhrsDataButton.ButtonPushedFcn = createCallbackFcn(app, @EventhrsDataButtonPushed, true);
            app.EventhrsDataButton.Text = 'Event-hrs';
            app.EventsDataButton = uibutton(app.ExportDataButtonGroup, 'push');
            app.EventsDataButton.ButtonPushedFcn = createCallbackFcn(app, @EventsDataButtonPushed, true);
            app.EventsDataButton.Text = 'Events';
            app.Heatmaps = uitab(app.TabGroup);
            app.Heatmaps.Title = 'Heatmaps';
            app.Grid_Heatmaps = uigridlayout(app.Heatmaps);
            app.HeatmapsGroup = uitabgroup(app.Grid_Heatmaps);
            app.EventhrsTab = uitab(app.HeatmapsGroup);
            app.EventhrsTab.Title = 'Event-hrs';
            app.HourofMaxDeficitvsMonthTab = uitab(app.HeatmapsGroup);
            app.HourofMaxDeficitvsMonthTab.Title = 'Hour of Max Deficit vs Month';
            app.DurationvsHourofMaxDeficitTab = uitab(app.HeatmapsGroup);
            app.DurationvsHourofMaxDeficitTab.Title = 'Duration vs Hour of Max Deficit';
            app.DurationvsMaxDeficitTab = uitab(app.HeatmapsGroup);
            app.DurationvsMaxDeficitTab.Title = 'Duration vs Max Deficit';
            app.DurationvsLossofEnergyTab = uitab(app.HeatmapsGroup);
            app.DurationvsLossofEnergyTab.Title = 'Duration vs Loss of Energy';
            app.OtherPlotsNYBAResultsPanel = uipanel(app.Grid_Heatmaps);
            app.OtherPlotsNYBAResultsPanel.Title = 'Other Plots: NYBA Results';
            app.LOLEdyyearPanel = uipanel(app.OtherPlotsNYBAResultsPanel);
            app.LOLEdyyearPanel.Title = 'LOLE (dy/year)';
            app.LOLEbox = uieditfield(app.LOLEdyyearPanel, 'numeric');
            app.LOLEbox.Limits = [0 Inf];
            app.LOLEbox.ValueDisplayFormat = '%.4f';
            app.LOLEbox.Editable = 'off';
            app.LOLHhryearPanel = uipanel(app.OtherPlotsNYBAResultsPanel);
            app.LOLHhryearPanel.Title = 'LOLH (hr/year)';
            app.LOLHbox = uieditfield(app.LOLHhryearPanel, 'numeric');
            app.LOLHbox.Limits = [0 Inf];
            app.LOLHbox.ValueDisplayFormat = '%.4f';
            app.LOLHbox.Editable = 'off';
            app.LOEEMWhyearPanel = uipanel(app.OtherPlotsNYBAResultsPanel);
            app.LOEEMWhyearPanel.Title = 'LOEE (MWh/year)';
            app.LOEEbox = uieditfield(app.LOEEMWhyearPanel, 'numeric');
            app.LOEEbox.Limits = [0 Inf];
            app.LOEEbox.ValueDisplayFormat = '%.4f';
            app.LOEEbox.Editable = 'off';
            app.LOLEplotwitheventhrsButton = uibutton(app.OtherPlotsNYBAResultsPanel, 'push');
            app.LOLEplotwitheventhrsButton.ButtonPushedFcn = createCallbackFcn(app, @LOLEplotwitheventhrsButtonPushed, true);
            app.LOLEplotwitheventhrsButton.Text = 'LOLE (plot with event-hrs)';
            app.LOLHplotwitheventhrsButton = uibutton(app.OtherPlotsNYBAResultsPanel, 'push');
            app.LOLHplotwitheventhrsButton.ButtonPushedFcn = createCallbackFcn(app, @LOLHplotwitheventhrsButtonPushed, true);
            app.LOLHplotwitheventhrsButton.Text = 'LOLH (plot with event-hrs)';
            app.LOEEplotwitheventhrsButton = uibutton(app.OtherPlotsNYBAResultsPanel, 'push');
            app.LOEEplotwitheventhrsButton.ButtonPushedFcn = createCallbackFcn(app, @LOEEplotwitheventhrsButtonPushed, true);
            app.LOEEplotwitheventhrsButton.Text = 'LOEE (plot with event-hrs)';
            app.Plots3D = uitab(app.TabGroup);
            app.Plots3D.Title = '3D';
            app.Grid_Plots3D = uigridlayout(app.Plots3D);
            app.Plots3DGroup = uitabgroup(app.Grid_Plots3D);
            app.Plots3DGroup.Tooltip = {''};
            app.EventsTab_3D_1_1 = uitab(app.Plots3DGroup);
            app.EventsTab_3D_1_1.Title = '1.1';
            app.UIAxes_3D_1_1 = uiaxes(app.EventsTab_3D_1_1);
            app.EventsTab_3D_1_2 = uitab(app.Plots3DGroup);
            app.EventsTab_3D_1_2.Title = '1.2';
            app.UIAxes_3D_1_2 = uiaxes(app.EventsTab_3D_1_2);
            app.EventsTab_3D_2_1 = uitab(app.Plots3DGroup);
            app.EventsTab_3D_2_1.Title = '2.1';
            app.UIAxes_3D_2_1 = uiaxes(app.EventsTab_3D_2_1);
            app.EventsTab_3D_2_2 = uitab(app.Plots3DGroup);
            app.EventsTab_3D_2_2.Title = '2.2';
            app.UIAxes_3D_2_2 = uiaxes(app.EventsTab_3D_2_2);
            app.EventsTab_3D_3_2 = uitab(app.Plots3DGroup);
            app.EventsTab_3D_3_2.Title = '3.2';
            app.UIAxes_3D_3_2 = uiaxes(app.EventsTab_3D_3_2);
            app.EventsTab_3D_4_1 = uitab(app.Plots3DGroup);
            app.EventsTab_3D_4_1.Title = '4.1';
            app.UIAxes_3D_4_1 = uiaxes(app.EventsTab_3D_4_1);
            app.DataTab_3D_1 = uitab(app.Plots3DGroup);
            app.DataTab_3D_1.Title = 'Data';
            app.UITable_3D_1 = uitable(app.DataTab_3D_1);
            app.UITable_3D_1.ColumnName = {'Replication'; 'Bin'; 'Month'; 'Day'; 'Duration'; 'Time_Max_Deficit'; 'Area_Max_Deficit'; 'NYBA_Max_Deficit'; 'NYBA_LOE'; 'NYBA_Load'; 'Time_Max_Excess'; 'Area_NYBA_Max_Excess'; 'NYBA_Max_Excess'; 'NYBA_Load_PND'; 'NY_A_Max_Deficit'; 'NY_B_Max_Deficit'; 'NY_C_Max_Deficit'; 'NY_D_Max_Deficit'; 'NY_E_Max_Deficit'; 'NY_F_Max_Deficit'; 'NY_G_Max_Deficit'; 'NY_H_Max_Deficit'; 'NY_I_Max_Deficit'; 'NY_J_Max_Deficit'; 'NY_K_Max_Deficit'};
            app.UITable_3D_1.RowName = {};
            app.EventsTab_3D_3_1 = uitab(app.Plots3DGroup);
            app.EventsTab_3D_3_1.Title = '3.1';
            app.UIAxes_3D_3_1 = uiaxes(app.EventsTab_3D_3_1);
            app.SelectPlotListBoxLabel = uilabel(app.Grid_Plots3D);
            app.SelectPlotListBoxLabel.Text = 'Select Plot';
            app.SelectPlotListBox = uilistbox(app.Grid_Plots3D);
            app.SelectPlotListBox.Items = {'(1.1) MW_Load / Hrs / Max Deficit', '(1.2) MW_Load / Hrs / Max Deficit- weighted by Loss of Energy', '(2.1) MW_Load / Hrs / Month', '(2.2) MW_Load / Hrs / Month - weighted by Loss of Energy', '(3.1) MW_Load / Hrs / Hour of Max Deficit', '(3.2) MW_Load / Hrs / Hour of Max Deficit - weighted by Loss of Energy', '(4.1) MW_Load / Hrs / Loss of Energy', 'Data'};
            app.SelectPlotListBox.ValueChangedFcn = createCallbackFcn(app, @SelectPlotListBoxValueChanged, true);
            app.SelectPlotListBox.Value = '(1.1) MW_Load / Hrs / Max Deficit';
            app.SelectTypeButtonGroup = uibuttongroup(app.Grid_Plots3D);
            app.SelectTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectTypeButtonGroupSelectionChanged, true);
            app.SelectTypeButtonGroup.Title = 'Select Type';
            app.AreaPlotsbyBinButton = uiradiobutton(app.SelectTypeButtonGroup);
            app.AreaPlotsbyBinButton.Text = 'Area Plots by Bin';
            app.NYBAPlotsbyBinButton = uiradiobutton(app.SelectTypeButtonGroup);
            app.NYBAPlotsbyBinButton.Text = 'NYBA Plots by Bin';
            app.NYBAPlotsbyBinButton.Value = true;
            app.ExportDataButton = uibutton(app.Grid_Plots3D, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.Plots2D = uitab(app.TabGroup);
            app.Plots2D.Title = '2D';
            app.Grid_Plots2D = uigridlayout(app.Plots2D);
            app.Plots2DGroup = uitabgroup(app.Grid_Plots2D);
            app.LOLEbyBinTab = uitab(app.Plots2DGroup);
            app.LOLEbyBinTab.Title = 'LOLE by Bin';
            app.UIAxes_2D_1_1 = uiaxes(app.LOLEbyBinTab);
            app.LOLHbyBinTab = uitab(app.Plots2DGroup);
            app.LOLHbyBinTab.Title = 'LOLH by Bin';
            app.UIAxes_2D_1_2 = uiaxes(app.LOLHbyBinTab);
            app.LOEEbyBinTab = uitab(app.Plots2DGroup);
            app.LOEEbyBinTab.Title = 'LOEE by Bin';
            app.UIAxes_2D_1_3 = uiaxes(app.LOEEbyBinTab);
            app.LOLEbyMarginTab = uitab(app.Plots2DGroup);
            app.LOLEbyMarginTab.Title = 'LOLE by Margin';
            app.UIAxes_2D_1_4 = uiaxes(app.LOLEbyMarginTab);
            app.LOLHbyMarginTab = uitab(app.Plots2DGroup);
            app.LOLHbyMarginTab.Title = 'LOLH by Margin';
            app.UIAxes_2D_1_5 = uiaxes(app.LOLHbyMarginTab);
            app.LOEEbyMarginTab = uitab(app.Plots2DGroup);
            app.LOEEbyMarginTab.Title = 'LOEE by Margin';
            app.UIAxes_2D_1_6 = uiaxes(app.LOEEbyMarginTab);
            app.DataTab_2 = uitab(app.Plots2DGroup);
            app.DataTab_2.Title = 'Data';
            app.UITable_2D = uitable(app.DataTab_2);
            app.UITable_2D.ColumnName = {'Area'; 'Margin_State'; 'Month'; 'LOLE'; 'LOLH'; 'LOEE'};
            app.UITable_2D.RowName = {};
            app.NYBAResultsPanel_2 = uipanel(app.Grid_Plots2D);
            app.NYBAResultsPanel_2.Title = 'NYBA Results';
            app.LOLEPlotwithEventsButton = uibutton(app.NYBAResultsPanel_2, 'push');
            app.LOLEPlotwithEventsButton.ButtonPushedFcn = createCallbackFcn(app, @LOLEPlotwithEventsButtonPushed, true);
            app.LOLEPlotwithEventsButton.Text = 'LOLE Plot with Events';
            app.LOEEPlotwithEventsButton = uibutton(app.NYBAResultsPanel_2, 'push');
            app.LOEEPlotwithEventsButton.ButtonPushedFcn = createCallbackFcn(app, @LOEEPlotwithEventsButtonPushed, true);
            app.LOEEPlotwithEventsButton.Text = 'LOEE Plot with Events';
            app.LOLHPlotwithEventsButton = uibutton(app.NYBAResultsPanel_2, 'push');
            app.LOLHPlotwithEventsButton.ButtonPushedFcn = createCallbackFcn(app, @LOLHPlotwithEventsButtonPushed, true);
            app.LOLHPlotwithEventsButton.Text = 'LOLH Plot with Events';
            app.InitialDateDatePickerLabel = uilabel(app.NYBAResultsPanel_2);
            app.InitialDateDatePickerLabel.Text = 'Initial Date';
            app.InitialDateDatePicker = uidatepicker(app.NYBAResultsPanel_2);
            app.FinalDateDatePicker = uidatepicker(app.NYBAResultsPanel_2);
            app.FinalDateDatePickerLabel = uilabel(app.NYBAResultsPanel_2);
            app.FinalDateDatePickerLabel.Text = 'Final Date';
            app.SummaryTab = uitab(app.TabGroup);
            app.SummaryTab.Title = 'Summary';
            app.GridSummary = uigridlayout(app.SummaryTab);
            app.SummaryTab_4 = uitabgroup(app.GridSummary);
            app.Tab_4 = uitab(app.SummaryTab_4);
            app.Tab_4.Title = 'Max Deficit';
            app.SummaryTab_3 = uitabgroup(app.GridSummary);
            app.Tab_3 = uitab(app.SummaryTab_3);
            app.Tab_3.Title = 'Max Deficit';
            app.SummaryTab_1 = uitabgroup(app.GridSummary);
            app.Tab_1 = uitab(app.SummaryTab_1);
            app.Tab_1.Title = 'Max Deficit';
            app.SummaryTab_2 = uitabgroup(app.GridSummary);
            app.Tab_2 = uitab(app.SummaryTab_2);
            app.Tab_2.Title = 'Max Deficit';
            app.menu = uipanel(app.Grid0);
            app.menu_Grid = uigridlayout(app.menu);
            app.menu_Grid_sub = uigridlayout(app.menu_Grid);
            app.menu_Dir = uigridlayout(app.menu_Grid_sub);
            app.DirectoryEditField = uieditfield(app.menu_Dir, 'text');
            app.DirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @DirectoryEditFieldValueChanged, true);
            app.Button = uibutton(app.menu_Dir, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '...';
            app.DirectoryLabel = uilabel(app.menu_Grid_sub);
            app.DirectoryLabel.Text = 'Directory';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Analysis_Toolbox

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end